classdef dicot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DICOT10UIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenInputImageseriesMenu       matlab.ui.container.Menu
        SaveImageMenu                  matlab.ui.container.Menu
        SaveParametersAsMenu           matlab.ui.container.Menu
        ExcelsheetxlsxMenu             matlab.ui.container.Menu
        TexttxtMenu                    matlab.ui.container.Menu
        csvcsvMenu                     matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        OperationsMenu                 matlab.ui.container.Menu
        DetectobjectsMenu              matlab.ui.container.Menu
        SoGMenu                        matlab.ui.container.Menu
        LoGMenu                        matlab.ui.container.Menu
        DoGMenu                        matlab.ui.container.Menu
        oDoGMenu                       matlab.ui.container.Menu
        TrackobjectsMenu               matlab.ui.container.Menu
        StatisticsMenu                 matlab.ui.container.Menu
        MakemovieMenu                  matlab.ui.container.Menu
        AdvancedMenu                   matlab.ui.container.Menu
        SetdiffusionmodelMenu          matlab.ui.container.Menu
        AnomalousMenu                  matlab.ui.container.Menu
        DriftMenu                      matlab.ui.container.Menu
        SetthresholdpowerMenu          matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        HistogramMenu                  matlab.ui.container.Menu
        PathlengthMenu                 matlab.ui.container.Menu
        DisplacementMenu_2             matlab.ui.container.Menu
        InstantaneousMenu              matlab.ui.container.Menu
        NetMenu                        matlab.ui.container.Menu
        SpeedMenu_2                    matlab.ui.container.Menu
        TortuosityMenu_2               matlab.ui.container.Menu
        TimeprofilesMenu               matlab.ui.container.Menu
        DisplacementMenu_3             matlab.ui.container.Menu
        SpeedMenu_3                    matlab.ui.container.Menu
        MSDMenu_4                      matlab.ui.container.Menu
        AveragewithfitMenu_2           matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        MarkersizeMenu                 matlab.ui.container.Menu
        LinewidthMenu                  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        UserGuideMenu                  matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        previewlabel                   matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        IdleLabel                      matlab.ui.control.Label
        saveparam                      matlab.ui.control.Image
        resetimage                     matlab.ui.control.Image
        savemovieimage                 matlab.ui.control.Image
        statsimage                     matlab.ui.control.Image
        helpImage                      matlab.ui.control.Image
        openimage                      matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
        PixelsizeEditFieldLabel        matlab.ui.control.Label
        PixelsizeEditField             matlab.ui.control.NumericEditField
        TimeintervalEditFieldLabel     matlab.ui.control.Label
        TimeintervalEditField          matlab.ui.control.NumericEditField
        timeUnitsDropDown              matlab.ui.control.DropDown
        toEditFieldLabel               matlab.ui.control.Label
        FrameendEditField              matlab.ui.control.NumericEditField
        pixelsizeunitlabel             matlab.ui.control.Label
        intervalunitlabel              matlab.ui.control.Label
        UnitsDropDownLabel             matlab.ui.control.Label
        distUnitsDropDown              matlab.ui.control.DropDown
        FramesEditFieldLabel           matlab.ui.control.Label
        FramestartEditField            matlab.ui.control.NumericEditField
        ObjectdetectionPanel           matlab.ui.container.Panel
        SensitivityfactorSlider        matlab.ui.control.Slider
        TypeofobjectstobedetectedButtonGroup  matlab.ui.container.ButtonGroup
        BrightButton                   matlab.ui.control.RadioButton
        DarkButton                     matlab.ui.control.RadioButton
        toEditFieldLabel_2             matlab.ui.control.Label
        toEditFieldLabel_3             matlab.ui.control.Label
        DetectobjectsButton            matlab.ui.control.Button
        SensitivityfactorSpinnerLabel  matlab.ui.control.Label
        SensitivityfactorSpinner       matlab.ui.control.Spinner
        SigmaSpinnerLabel              matlab.ui.control.Label
        SigmaSpinner                   matlab.ui.control.Spinner
        KernelsizeSpinnerLabel         matlab.ui.control.Label
        KernelsizeSpinner              matlab.ui.control.Spinner
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        SigmaSpinner_2                 matlab.ui.control.Spinner
        TrackingPanel                  matlab.ui.container.Panel
        TrackobjectsButton             matlab.ui.control.Button
        searchradiusunits              matlab.ui.control.Label
        perstimeunitlabel              matlab.ui.control.Label
        SearchradiusSpinnerLabel       matlab.ui.control.Label
        SearchradiusSpinner            matlab.ui.control.Spinner
        MinimaltracktimeSpinnerLabel   matlab.ui.control.Label
        MinimaltracktimeSpinner        matlab.ui.control.Spinner
        MSDButton                      matlab.ui.control.Button
        SavestatisticsButton           matlab.ui.control.Button
        frameSlider                    matlab.ui.control.Slider
        viewmodegroup                  matlab.ui.container.ButtonGroup
        DetectionButton                matlab.ui.control.RadioButton
        TrackingButton                 matlab.ui.control.RadioButton
        RawButton                      matlab.ui.control.RadioButton
        toEditFieldLabel_6             matlab.ui.control.Label
        toEditFieldLabel_7             matlab.ui.control.Label
    end

    
    properties (Access = private)
        fullfilename % Name of tif image series
        framestart=1;
        frameend=1;
        img
        info
        pixsz=1;
        timeunit='s';
        distunit='um';
        Findex
        interval=1;
        ksize=1;
        sigma=1;
        phi=0;
        lambda=1;
        searchradius=1;
        perstime=5;
        subfolder % Folder where input and output will be stored
        typ='Bright';
        objno
        fno
        view='Raw';
        diffmodel='anomalous';
        un
        rang
        filtertype='SoG';
        sigma2=1;
        markersz=8;
        lwid=1;
        threshpow=2;
        filename
        pathname
    end
    
    properties (Access = public)
        outmat
        segmented
        allpertrack
        allinst
        ALLtracks% Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(2)
        end

        % Menu selected function: OpenInputImageseriesMenu
        function OpenInputImageseriesMenuSelected(app, event)
            
            app.DICOT10UIFigure.Visible='off';
            [filen,app.pathname]=uigetfile('*.tif', 'Select an image time-series (.tif)');
            if filen==0
                % user pressed cancel
                app.previewlabel.Visible='on';
                app.previewlabel.Text='Error: No image selected';
                app.previewlabel.BackgroundColor='r';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                app.DICOT10UIFigure.Visible='on';
                drawnow
                return
                %figure(app.DICOT10UIFigure);
            else
                cla(app.UIAxes);
                [~,app.filename, ext]=fileparts(filen);
                switch ext
                    case {'.tif', '.tiff'}
                        
                        
                        app.DICOT10UIFigure.Visible='on';
                        app.StatusLamp.Color= 'red';
                        app.IdleLabel.Text='Busy';
                        app.RawButton.Enable='on';
                        app.view='Raw';
                        drawnow
                        
                        
                        app.fullfilename=[app.pathname,filen];
                        app.info=imfinfo(app.fullfilename);
                        
                        app.FramestartEditField.Value(1) = 1;
                        app.FrameendEditField.Value(1) = numel(app.info);
                        app.frameend=numel(app.info);
                        app.framestart=1;
                        app.fno=1;
                        switch app.FrameendEditField.Value(1)
                            case 1
                                app.frameSlider.Enable='off';
                                
                                
                            otherwise
                                app.frameSlider.Enable='on';
                                app.frameSlider.Limits=[app.framestart, app.frameend];
                                app.frameSlider.MajorTicks=app.framestart:(app.frameend-app.framestart):app.frameend;
                                
                        end
                        drawnow
                        app.Findex=app.FramestartEditField.Value(1):app.FrameendEditField.Value(1);
                        app.Label.Text=app.fullfilename;
                        app.Label.Visible='on';
                        drawnow
                        
                        Xres=app.info.XResolution;
                        if isempty(Xres)==0
                            app.pixsz=round((1/Xres),2);
                        else
                            app.pixsz=1;
                        end
                        app.PixelsizeEditField.Value(1)=app.pixsz;
                        drawnow
                        
                        for u=app.Findex
                            app.img{u}=imread(app.fullfilename,u);
                        end
                        
                        drawnow
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        
                        
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                        app.DetectionButton.Enable='on';
                        
                        app.TrackingButton.Enable='off';
                        app.StatusLamp.Color= 'green';
                        app.IdleLabel.Text='Idle';
                        app.subfolder=[app.pathname, '/DICOT-', app.filename,...
                            '(', sprintf('%i',app.framestart), '-', sprintf('%i',app.frameend), ')'];
                        
                        drawnow
                        figure(app.DICOT10UIFigure);
                        
                        
                    otherwise
                        
                        app.DICOT10UIFigure.Visible='on';
                        app.previewlabel.Visible='on';
                        app.previewlabel.Text='Error: Please select a .tif file';
                        app.previewlabel.BackgroundColor='r';
                        app.StatusLamp.Color= 'green';
                        app.IdleLabel.Text='Idle';
                end
            end
            
        end

        % Image clicked function: openimage
        function openimageImageClicked(app, event)
            
            app.DICOT10UIFigure.Visible='off';
            [filen,app.pathname]=uigetfile('*.tif', 'Select an image time-series (.tif)');
            if filen==0
                % user pressed cancel
                app.previewlabel.Visible='on';
                app.previewlabel.Text='Error: No image selected';
                app.previewlabel.BackgroundColor='r';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                app.DICOT10UIFigure.Visible='on';
                drawnow
                return
                %figure(app.DICOT10UIFigure);
            else
                cla(app.UIAxes);
                [~,app.filename, ext]=fileparts(filen);
                switch ext
                    case {'.tif', '.tiff'}
                        
                        
                        app.DICOT10UIFigure.Visible='on';
                        app.StatusLamp.Color= 'red';
                        app.IdleLabel.Text='Busy';
                        app.RawButton.Enable='on';
                        app.view='Raw';
                        drawnow
                        
                        
                        app.fullfilename=[app.pathname,filen];
                        app.info=imfinfo(app.fullfilename);
                        
                        app.FramestartEditField.Value(1) = 1;
                        app.FrameendEditField.Value(1) = numel(app.info);
                        app.frameend=numel(app.info);
                        app.framestart=1;
                        app.fno=1;
                        switch app.FrameendEditField.Value(1)
                            case 1
                                app.frameSlider.Enable='off';
                                
                                
                            otherwise
                                app.frameSlider.Enable='on';
                                app.frameSlider.Limits=[app.framestart, app.frameend];
                                app.frameSlider.MajorTicks=app.framestart:(app.frameend-app.framestart):app.frameend;
                                
                        end
                        drawnow
                        app.Findex=app.FramestartEditField.Value(1):app.FrameendEditField.Value(1);
                        app.Label.Text=app.fullfilename;
                        app.Label.Visible='on';
                        drawnow
                        
                        Xres=app.info.XResolution;
                        if isempty(Xres)==0
                            app.pixsz=round((1/Xres),2);
                        else
                            app.pixsz=1;
                        end
                        app.PixelsizeEditField.Value(1)=app.pixsz;
                        drawnow
                        
                        for u=app.Findex
                            app.img{u}=imread(app.fullfilename,u);
                        end
                        
                        drawnow
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        
                        
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                        app.DetectionButton.Enable='on';
                        
                        app.TrackingButton.Enable='off';
                        app.StatusLamp.Color= 'green';
                        app.IdleLabel.Text='Idle';
                        app.subfolder=[app.pathname, '/DICOT-', app.filename,...
                            '(', sprintf('%i',app.framestart), '-', sprintf('%i',app.frameend), ')'];
                        
                        drawnow
                        figure(app.DICOT10UIFigure);
                        
                        
                    otherwise
                        
                        app.DICOT10UIFigure.Visible='on';
                        app.previewlabel.Visible='on';
                        app.previewlabel.Text='Error: Please select a .tif file';
                        app.previewlabel.BackgroundColor='r';
                        app.StatusLamp.Color= 'green';
                        app.IdleLabel.Text='Idle';
                end
            end
        end

        % Value changed function: FramestartEditField
        function FramestartEditFieldValueChanged(app, event)
            app.view='Raw';
            app.DetectionButton.Enable='on';
            app.framestart= app.FramestartEditField.Value(1);
            app.Findex=app.FramestartEditField.Value(1):app.FrameendEditField.Value(1);
            app.subfolder=[app.pathname, '/DICOT-', app.filename,...
                '(', sprintf('%i',app.framestart), '-', sprintf('%i',app.frameend), ')'];
            drawnow
            if app.framestart<app.frameend
                
                app.frameSlider.Limits=[app.framestart,app.frameend];
                app.frameSlider.MajorTicks=app.framestart:(app.frameend-app.framestart):app.frameend;
                app.fno=floor(app.frameSlider.Value);
                for u=app.Findex
                    app.img{u}=imread(app.fullfilename,u);
                end
                drawnow
            else
                app.frameend=app.framestart;
                app.frameSlider.Enable='off';
                app.frameSlider.Limits=[]
                app.fno=app.framestart;
                app.FrameendEditField.Value(1)=app.framestart;
                drawnow
            end
            try
                cla(app.UIAxes);
                imshow(imadjust(app.img{app.fno}),...
                    'Border', 'tight',...
                    'Parent', app.UIAxes)
                drawnow
            catch
                
            end
            
        end

        % Value changed function: FrameendEditField
        function FrameendEditFieldValueChanged(app, event)
            app.view='Raw';
            app.DetectionButton.Enable='on';
            app.previewlabel.Visible='on';
            app.frameend = app.FrameendEditField.Value(1);
            app.Findex=app.FramestartEditField.Value(1):app.FrameendEditField.Value(1);
            app.frameSlider.Limits=[app.framestart,app.frameend];
            app.frameSlider.MajorTicks=app.framestart:(app.frameend-app.framestart):app.frameend;
            app.fno=floor(app.frameSlider.Value);
            app.subfolder=[app.pathname, '/DICOT-', app.filename,...
                '(', sprintf('%i',app.framestart), '-', sprintf('%i',app.frameend), ')'];
            drawnow
            
            for u=app.Findex
                app.img{u}=imread(app.fullfilename,u);
            end
            cla(app.UIAxes);
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            drawnow
        end

        % Value changed function: timeUnitsDropDown
        function timeUnitsDropDownValueChanged(app, event)
            timeval= app.timeUnitsDropDown.Value;
            if strcmp(timeval,'s')
                app.timeunit='s';
            elseif strcmp(timeval,'min')
                app.timeunit='min';
            elseif strcmp(timeval,'h')
                app.timeunit='h';
            elseif strcmp(timeval,'frame')
                app.timeunit='frame';
            end
            app.intervalunitlabel.Text = app.timeunit;
            app.perstimeunitlabel.Text = app.timeunit;
            drawnow
        end

        % Value changed function: distUnitsDropDown
        function distUnitsDropDownValueChanged(app, event)
            distval= app.distUnitsDropDown.Value;
            if strcmp(distval,'um')
                app.distunit='um';
            elseif strcmp(distval,'nm')
                app.distunit='nm';
            elseif strcmp(distval,'pix')
                app.distunit='pix';
            elseif strcmp(distval,'mm')
                app.distunit='mm';
            end
            
            drawnow
            app.pixelsizeunitlabel.Text = [app.distunit,'/pixel'];
            app.searchradiusunits.Text=app.distunit;
            drawnow
        end

        % Value changed function: PixelsizeEditField
        function PixelsizeEditFieldValueChanged(app, event)
            app.pixsz = round(app.PixelsizeEditField.Value(1),2);
            drawnow
        end

        % Value changed function: TimeintervalEditField
        function TimeintervalEditFieldValueChanged(app, event)
            app.interval = app.TimeintervalEditField.Value;
            app.perstime=10*app.interval;
            app.MinimaltracktimeSpinner.Value=app.perstime;
            app.MinimaltracktimeSpinner.Step=app.interval;
            drawnow
        end

        % Value changing function: frameSlider
        function frameSliderValueChanging(app, event)
            app.fno = round(event.Value);
            app.frameSlider.Tooltip=num2str(round(event.Value));
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            drawnow
            
            switch app.view
                case 'Raw'
                    if ~isempty(app.img)
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    end
                case 'Detection'
                    app.previewlabel.Visible='off';
                    cla(app.UIAxes);
                    
                    imshow(imadjust(app.img{app.fno}),...
                        'Parent', app.UIAxes,...
                        'Border', 'tight',...
                        'InitialMagnification', 'fit');
                    
                    drawnow
                    switch app.filtertype
                        case 'SoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                            kk = fspecial('gaussian',app.ksize,app.sigma);
                            km = mean(kk(:));
                            ki = app.lambda*(kk - km) - app.phi.*km;
                        case 'DoG'
                            app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            ki = app.lambda*(-Gauss_1 + Gauss_2);
                        case 'LoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            ki = fspecial('log', app.ksize, app.sigma);
                            ki  = -app.lambda*ki;
                        case 'oDoG'
                            app.SigmaSpinner_2.Enable='on';
                            app.SensitivityfactorSlider.Enable='off';
                            app.SensitivityfactorSpinner.Enable='off';
                            app.SensitivityfactorSpinnerLabel.Enable='off';
                            drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            
                            %first expanded gaussian in the upper right corner of the matrix
                            G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                            G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                            
                            %second expanded gaussian in the lower left corner of the matrix
                            G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                            G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                            
                            %difference of offset gaussian
                            ki=zeros(2*app.ksize,2*app.ksize);
                            ki =  G1 - G2; %offset DoG
                    end
                    
                    imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
                    imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                    % quantify
                    stat = regionprops(imgig,'Centroid');
                    scen = [stat.Centroid];
                    hold(app.UIAxes, 'on')
                    if ~isempty(scen)
                        plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                            app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    end
                    
                case 'Tracking'
                    app.previewlabel.Visible='off';
                    %rang=hsv(app.objno);
                    cla(app.UIAxes);
                    
                    imshow(imadjust(app.img{app.fno}),...
                        'Parent', app.UIAxes,...
                        'Border', 'tight',...
                        'InitialMagnification', 'fit');
                    
                    that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                    
                    for dd=1:numel(that)
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(:,3),...
                            app.ALLtracks{that(dd)}(:,4),...
                            'Color', app.rang(dd,:),...
                            'LineWidth', app.lwid,...
                            'LineStyle','-',...
                            'Marker','none')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                            '.k',...
                            'MarkerSize',app.markersz+2)
                    end
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
        end

        % Selection changed function: 
        % TypeofobjectstobedetectedButtonGroup
        function TypeofobjectstobedetectedButtonGroupSelectionChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.typ = app.TypeofobjectstobedetectedButtonGroup.SelectedObject.Text;
            switch app.typ
                case 'Bright'
                    app.lambda=1;
                case 'Dark'
                    app.lambda=-1;
            end
            drawnow
            %-----preview------
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
            end
            cla(app.UIAxes);
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    
            end
            %--------end preview-----
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changing function: SensitivityfactorSlider
        function SensitivityfactorSliderValueChanging(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                drawnow
                
                return
            end
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            drawnow
            app.phi = round(event.Value,2);
            app.SensitivityfactorSpinner.Value=app.phi;
            app.SensitivityfactorSlider.Tooltip=num2str(round(event.Value,2), 3);
            drawnow
            %-----preview------
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            kk = fspecial('gaussian',app.ksize,app.sigma);
            km = mean(kk(:));
            % sog
            ki = app.lambda*(kk -km) - app.phi.*km;
            cla(app.UIAxes);
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];%cat(1,stat.Centroid); %ARC changed 28/4/2020
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    drawnow
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Button pushed function: DetectobjectsButton
        function DetectobjectsButtonPushed(app, event)
            
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            drawnow
            %---code---
            listLen= cell(1,max(app.Findex));
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
                    %                 case 1
                    %                     app.previewlabel.Visible= 'on';
                    %                     app.previewlabel.BackgroundColor='r';
                    %                     app.previewlabel.Text='Please rename the previously existing folder to prevent overwriting.';
                    %                     drawnow
                    
                    
            end
            %             outfile=[app.subfolder, '/segmentedmovie.tif'];
            %             if exist(outfile, 'file')
            %                 delete(outfile);
            %             end
            p=0;
            w =waitbar(0,'Detecting objects..',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(w,'canceling',0);


            for image_no=app.Findex
                p=p+1;
                waitbar(p/numel(app.Findex));
                % Check for clicked Cancel button
                if getappdata(w,'canceling')
                    delete(w);
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    app.segmented=[];
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: Action cancelled';
                    
                    drawnow
                    return
                end
                
                switch app.filtertype
                    case 'SoG'
                        app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                        kk = fspecial('gaussian',app.ksize,app.sigma);
                        km = mean(kk(:));
                        ki = app.lambda*(kk - km) - app.phi.*km;
                    case 'DoG'
                        app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                        Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                        Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                        ki = app.lambda*(-Gauss_1 + Gauss_2);
                    case 'LoG'
                        app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                        ki = fspecial('log', app.ksize, app.sigma);
                        ki  = -app.lambda*ki;
                    case 'oDoG'
                        app.SigmaSpinner_2.Enable='on';
                        app.SensitivityfactorSlider.Enable='off';
                        app.SensitivityfactorSpinner.Enable='off';
                        app.SensitivityfactorSpinnerLabel.Enable='off';
                        drawnow
                        Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                        Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                        
                        %first expanded gaussian in the upper right corner of the matrix
                        G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                        G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                        %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                        
                        %second expanded gaussian in the lower left corner of the matrix
                        G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                        G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                        %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                        
                        %difference of offset gaussian
                        ki=zeros(2*app.ksize,2*app.ksize);
                        ki =  G1 - G2; %offset DoG
                end
                imgig = imadjust(imfilter(app.img{image_no},ki,'replicate','same'));
                imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                % quantify
                stat = regionprops(imgig,'Centroid');
                scen=cat(1,stat.Centroid);
                frameno=(ones(size(scen,1),1))*image_no;
                listLen{image_no}=[frameno,scen];
                
            end
            delete(w)
            figure(app.DICOT10UIFigure);
            try
                app.segmented = cat(1, listLen{:});%1:frame no, 2:obj no, 3:x, 4:y
            catch ME
                if (strcmp(ME.identifier,'MATLAB:catenate:dimensionMismatch'))
                    %msg = 'Please tweak parameters';
                    causeException = MException('MATLAB:myCode:dimensions',msg);
                    ME = addCause(ME,causeException);
                end
            end
            
            switch isempty(app.segmented)
                case 1
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: No objects detected. Please tweak parameters.';
                    
                    drawnow
                    
                case 0
                    fid =fopen([app.subfolder,'/segmented.txt'], 'w');
                    fprintf(fid, 'Frame    X    Y\r\n');
                    fclose(fid);
                    dlmwrite([app.subfolder,'/segmented.txt'], app.segmented, '-append',...
                        'delimiter', '\t','newline', 'pc','precision', '%.3f');
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0,0.2,0.6];
                    app.previewlabel.Text='Detection complete.';
                    drawnow
            end
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            app.TrackingButton.Enable='on';
            
            drawnow
            
        end

        % Menu selected function: SoGMenu
        function SoGMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Open image first';
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            
            drawnow
            
            return
            end
            app.SigmaSpinner_2.Enable='off';
            app.DetectionButton.Enable='on';
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            app.FilterDropDown.Value='SoG';
            drawnow
            %---code---
            listLen= cell(1,max(app.Findex));
            if exist(app.subfolder, 'dir')
                app.previewlabel.Visible= 'on';
                %                 app.previewlabel.BackgroundColor='r';
                %                 app.previewlabel.Text='Please rename the previously existing folder to prevent overwriting.';
                drawnow
                
            else
                mkdir(app.subfolder);
                
            end
            %                         outsegfile=[app.subfolder, '/segmented.txt'];
            %                         if exist(outsegfile, 'file')
            %
            %                             delete(outsegfile);
            %                         end
            p=0;
            w =waitbar(0,'Detecting objects..',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            %
            setappdata(w,'canceling',0);
            
            
            for image_no=app.Findex
                p=p+1;
                waitbar(p/numel(app.Findex));
                % Check for clicked Cancel button
                if getappdata(w,'canceling')
                    delete(w);
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    app.segmented=[];
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: Action cancelled';
                    drawnow
                    return
                end
                kk = fspecial('gaussian',app.ksize,app.sigma);
                km = mean(kk(:));
                % sog
                ki = app.lambda*(kk - km) - app.phi.*km;
                imgig = imadjust(imfilter(app.img{image_no},ki,'replicate','same'));
                imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                % quantify
                stat = regionprops(imgig,'Centroid');
                scen = cat(1,stat.Centroid);
                frameno=(ones(size(scen,1),1))*image_no;
                listLen{image_no}=[frameno,scen];
            end
            
            delete(w);
            figure(app.DICOT10UIFigure);
            
            try
                app.segmented = cat(1, listLen{:});%1:frame no, 2:obj no, 3:x, 4:y
            catch ME
                if (strcmp(ME.identifier,'MATLAB:catenate:dimensionMismatch'))
                    %msg = 'Please tweak parameters';
                    causeException = MException('MATLAB:myCode:dimensions',msg);
                    ME = addCause(ME,causeException);
                end
            end
            switch isempty(app.segmented)
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: No objects detected. Please tweak parameters.';
                    drawnow
                case 0
                    fid =fopen([app.subfolder,'/segmented.txt'], 'w');
                    fprintf(fid, 'Frame    X    Y\r\n');
                    fclose(fid);
                    dlmwrite([app.subfolder,'/segmented.txt'], app.segmented, '-append',...
                        'delimiter', '\t','newline', 'pc','precision', '%.3f');
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0,0.2,0.6];
                    app.previewlabel.Text='Detection complete.';
                    drawnow
            end
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Menu selected function: DoGMenu
        function DoGMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                drawnow
                
                return
            end
            app.SensitivityfactorSlider.Enable='off';
            app.SensitivityfactorSpinner.Enable='off';
            app.SensitivityfactorSpinnerLabel.Enable='off';
            app.SigmaSpinner_2.Enable='on';
            app.FilterDropDown.Value='DoG';
            app.DetectionButton.Enable='on';
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            drawnow
            %---code---
            listLen= cell(1,max(app.Findex));
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
                    %                 case 1
                    %                     app.previewlabel.Visible= 'on';
                    %                     app.previewlabel.BackgroundColor='r';
                    %                     app.previewlabel.Text='Please rename the previously existing folder to prevent overwriting.';
                    %                     drawnow
                    
            end
            %
            p=0;
            
            w =waitbar(0,'Detecting objects..',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(w,'canceling',0);
            
            for image_no=app.Findex
                p=p+1;
                waitbar(p/numel(app.Findex));
                % Check for clicked Cancel button
                if getappdata(w,'canceling')
                    delete(w);
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    app.segmented=[];
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: Action cancelled';
                    drawnow
                    return
                end
                % dog
                Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                ki = app.lambda*(-Gauss_1 + Gauss_2);
                imgig = imadjust(imfilter(app.img{image_no},ki,'replicate','same'));
                imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                % quantify
                stat = regionprops(imgig,'Centroid');
                scen = cat(1,stat.Centroid);
                frameno=(ones(size(scen,1),1))*image_no;
                listLen{image_no}=[frameno,scen];
            end
            
            delete(w)
            figure(app.DICOT10UIFigure);
            
            try
                app.segmented = cat(1, listLen{:});%1:frame no, 2:obj no, 3:x, 4:y
            catch ME
                if (strcmp(ME.identifier,'MATLAB:catenate:dimensionMismatch'))
                    %msg = 'Please tweak parameters';
                    causeException = MException('MATLAB:myCode:dimensions',msg);
                    ME = addCause(ME,causeException);
                end
            end
            switch isempty(app.segmented)
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: No objects detected. Please tweak parameters.';
                    %drawnow
                case 0
                    fid =fopen([app.subfolder,'/segmented.txt'], 'w');
                    fprintf(fid, 'Frame    X    Y\r\n');
                    fclose(fid);
                    dlmwrite([app.subfolder,'/segmented.txt'], app.segmented, '-append',...
                        'delimiter', '\t','newline', 'pc','precision', '%.3f');
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0,0.2,0.6];
                    app.previewlabel.Text='Detection complete.';
                    %drawnow
            end
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Menu selected function: LoGMenu
        function LoGMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                drawnow
                
                return
            end
            app.SensitivityfactorSlider.Enable='off';
            app.SensitivityfactorSpinner.Enable='off';
            app.SensitivityfactorSpinnerLabel.Enable='off';
            app.SigmaSpinner_2.Enable='off';
            app.FilterDropDown.Value='LoG';
            app.DetectionButton.Enable='on';
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            drawnow
            %---code---
            listLen= cell(1,max(app.Findex));
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
                    
            end
            %
            p=0;
            w =waitbar(0,'Detecting objects..',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(w,'canceling',0);
            
            
            for image_no=app.Findex
                p=p+1;
                waitbar(p/numel(app.Findex));
                % Check for clicked Cancel button
                if getappdata(w,'canceling')
                    delete(w);
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    app.segmented=[];
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: Action cancelled';
                    drawnow
                    return
                end
                % log
                ki = fspecial('log', app.ksize, app.sigma);
                ki  = -app.lambda*ki;
                imgig = imadjust(imfilter(app.img{image_no},ki,'replicate','same'));
                imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                % quantify
                stat = regionprops(imgig,'Centroid');
                scen = cat(1,stat.Centroid);
                frameno=(ones(size(scen,1),1))*image_no;
                listLen{image_no}=[frameno,scen];
            end
            
            delete(w)
            figure(app.DICOT10UIFigure);
            
            try
                app.segmented = cat(1, listLen{:});%1:frame no, 2:obj no, 3:x, 4:y
            catch ME
                if (strcmp(ME.identifier,'MATLAB:catenate:dimensionMismatch'))
                    %msg = 'Please tweak parameters';
                    causeException = MException('MATLAB:myCode:dimensions',msg);
                    ME = addCause(ME,causeException);
                end
            end
            switch isempty(app.segmented)
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: No objects detected. Please tweak parameters.';
                    drawnow
                case 0
                    fid =fopen([app.subfolder,'/segmented.txt'], 'w');
                    fprintf(fid, 'Frame    X    Y\r\n');
                    fclose(fid);
                    dlmwrite([app.subfolder,'/segmented.txt'], app.segmented, '-append',...
                        'delimiter', '\t','newline', 'pc','precision', '%.3f');
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0,0.2,0.6];
                    app.previewlabel.Text='Detection complete.';
                    drawnow
            end
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Button pushed function: TrackobjectsButton
        function TrackobjectsButtonPushed(app, event)
            if isempty(app.subfolder) || isempty(app.segmented)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Detect objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            
            app.TrackingButton.Enable='on';
            app.TrackingButton.Value=1;
            app.view='Tracking';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.TrackingButton.Enable='on';
            app.TrackingButton.Value=1;
            app.view='Tracking';
            drawnow
            [app.ALLtracks,app.objno,app.outmat,app.un,o]= trackobjects(app.subfolder,...
                app.segmented,...
                app.searchradius,...
                app.pixsz,...
                app.interval,...
                app.timeunit,...
                app.perstime);
            figure(app.DICOT10UIFigure);
            %----------------code---------------------
            if o==0
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                app.previewlabel.Visible='on';
                
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Action cancelled';
                drawnow
            else
                
                app.rang=hsv(app.objno);
                cla(app.UIAxes);
                imshow(imadjust(app.img{app.fno}),...
                    'Border', 'tight',...
                    'Parent', app.UIAxes)
                that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                for dd=1:numel(that)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,...
                        app.ALLtracks{that(dd)}(:,3),...
                        app.ALLtracks{that(dd)}(:,4),...
                        'Color', app.rang(dd,:),...
                        'LineWidth', app.lwid,...
                        'LineStyle','-',...
                        'Marker','none')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,...
                        app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                        app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                        '.k',...
                        'MarkerSize',app.markersz+2)
                end
                app.previewlabel.Visible='on';
                
                app.previewlabel.BackgroundColor=[0.3,0,0.1];
                app.previewlabel.Text=['Tracking complete. ' ...
                    sprintf('%i',app.objno), ' tracks made.'];
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
            end
        end

        % Menu selected function: TrackobjectsMenu
        function TrackobjectsMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.segmented)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Detect objects first';
                
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                
                drawnow
                
                return
            end
            app.TrackingButton.Enable='on';
            app.TrackingButton.Value=1;
            app.view='Tracking';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.TrackingButton.Enable='on';
            app.TrackingButton.Value=1;
            app.view='Tracking';
            drawnow
            [app.ALLtracks,app.objno,app.outmat,app.un,o]= trackobjects(app.subfolder,...
                app.segmented,...
                app.searchradius,...
                app.pixsz,...
                app.interval,...
                app.timeunit,...
                app.perstime);
            figure(app.DICOT10UIFigure);
            %----------------code---------------------
            if o==0
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                app.previewlabel.Visible='on';
                
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Action cancelled';
                drawnow
            else
                
                app.rang=hsv(app.objno);
                cla(app.UIAxes);
                imshow(imadjust(app.img{app.fno}),...
                    'Border', 'tight',...
                    'Parent', app.UIAxes)
                that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                for dd=1:numel(that)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,...
                        app.ALLtracks{that(dd)}(:,3),...
                        app.ALLtracks{that(dd)}(:,4),...
                        'Color', app.rang(dd,:),...
                        'LineWidth', app.lwid,...
                        'LineStyle','-',...
                        'Marker','none')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,...
                        app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                        app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                        '.k',...
                        'MarkerSize',app.markersz+2)
                end
                app.previewlabel.Visible='on';
                
                app.previewlabel.BackgroundColor=[0.3,0,0.1];
                app.previewlabel.Text=['Tracking complete. ' ...
                    sprintf('%i',app.objno), ' tracks made.'];
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
            end
        end

        % Selection changed function: viewmodegroup
        function viewmodegroupSelectionChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.view = app.viewmodegroup.SelectedObject.Text;
            drawnow
            switch app.view
                case 'Raw'
                    cla(app.UIAxes);
                    if ~isempty(app.img)
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    end
                case 'Detection'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Detection mode';
                    drawnow
                    imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', app.UIAxes)
                    switch app.filtertype
                        case 'SoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                            kk = fspecial('gaussian',app.ksize,app.sigma);
                            km = mean(kk(:));
                            ki = app.lambda*(kk - km) - app.phi.*km;
                        case 'DoG'
                            app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            ki = app.lambda*(-Gauss_1 + Gauss_2);
                        case 'LoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            ki = fspecial('log', app.ksize, app.sigma);
                            ki  = -app.lambda*ki;
                        case 'oDoG'
                            app.SigmaSpinner_2.Enable='on';
                            app.SensitivityfactorSlider.Enable='off';
                            app.SensitivityfactorSpinner.Enable='off';
                            app.SensitivityfactorSpinnerLabel.Enable='off';
                            drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            
                            %first expanded gaussian in the upper right corner of the matrix
                            G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                            G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                            
                            %second expanded gaussian in the lower left corner of the matrix
                            G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                            G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                            
                            %difference of offset gaussian
                            ki=zeros(2*app.ksize,2*app.ksize);
                            ki =  G1 - G2; %offset DoG
                    end
                    imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
                    imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                    % quantify
                    stat = regionprops(imgig,'Centroid');
                    scen = [stat.Centroid];
                    hold(app.UIAxes, 'on')
                    switch isempty(scen)
                        case 0
                            plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                                app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                        case 1
                            app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[1,0,0];
                            app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                            drawnow
                    end
                case 'Tracking'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Tracking mode';
                    drawnow
                    imshow(imadjust(app.img{app.fno}),...
                        'Border', 'tight',...
                        'Parent', app.UIAxes)
                    that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                    for dd=1:numel(that)
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(:,3),...
                            app.ALLtracks{that(dd)}(:,4),...
                            'Color', app.rang(dd,:),...
                            'LineWidth', app.lwid,...
                            'LineStyle','-',...
                            'Marker','none')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                            '.k',...
                            'MarkerSize',app.markersz+2)
                    end
                    
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Button pushed function: SavestatisticsButton
        function SavestatisticsButtonPushed(app, event)
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            drawnow
            [app.allpertrack,app.allinst]=objectstats1(app.subfolder,...
                app.objno,...
                app.pixsz,...
                app.distunit,...
                app.timeunit,...
                app.outmat,...
                app.un);
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.85,0.33,0.10];
            app.previewlabel.Text='Saved statistics files.';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Button pushed function: MSDButton
        function MSDButtonPushed(app, event)
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='none';
            %app.previewlabel.Text='MSD';
            drawnow
            switch ishandle(10003)
                case 1
                    close(10003)
            end
            fj=figure(10003);
            fjax=axes(fj);
            set(fj,'NumberTitle','off', 'Name', 'MSD Profiles')
            set(fjax,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Time (', app.timeunit, ')'])
            ylabel(['MSD (', app.distunit, '^2)'])
            for idd=1:app.objno
                loc=find(app.outmat(:,1)==app.un(idd));
                [dt{idd}, rr{idd}]=msdisplacement_ARCmod1(app.outmat(loc,5),...
                    app.outmat(loc,3)*app.pixsz,...
                    app.outmat(loc,4)*app.pixsz);
                id{idd}=ones(size(dt{idd},1),1)*idd;
                
                hold(fjax,'on')
                plot(fjax,dt{idd},rr{idd},'-',...
                    'Color', [0.8 0.8 0.8],...
                    'LineWidth', app.lwid)
                
            end
            %%
            %             print(gcf, '-dtiffnocompression',...
            %                 [app.subfolder, '/MSD_vs_Time.tif']);
            % text file stored
            catiddtrr=[cat(1, id{:}),cat(1, dt{:}),cat(1, rr{:})];
            fid =fopen([app.subfolder, '/ID_Time_MSD.txt'], 'w');
            fprintf(fid, ['ObjectID    Timestep (', app.timeunit, ')    MSD (', app.distunit, '^2/', app.timeunit, ')\r\n']);
            fclose(fid);
            dlmwrite([app.subfolder, '/ID_Time_MSD.txt'],...
                catiddtrr,'-append', 'delimiter',...
                '\t','newline', 'pc',...
                'precision', '%.3f');
            % average msd curve with fit
            t_avg_sd=[];
            
            for yen=app.interval:app.interval:max(catiddtrr(:,2))
                locating=find(catiddtrr(:,2)==yen);
                t_avg_sd= [t_avg_sd;...
                    yen,mean(catiddtrr(locating,3)),std(catiddtrr(locating,3))];
            end
            %             dlmwrite([app.subfolder, '/T_Avg_SD.txt'],...
            %                 t_avg_sd,'-append', 'delimiter',...
            %                 '\t','newline', 'pc',...
            %                 'precision', '%.3f');
            hold(fjax,'on')
            plot(fjax,...
                [0;t_avg_sd(:,1)],...
                [0;t_avg_sd(:,2)],...
                '-','Color', 'r', 'Linewidth', app.lwid+1)
            
            xl = xlim(gca);
            xlim(gca, [0 xl(2)]);
            yl = ylim(gca);
            ylim(gca, [0 yl(2)]);
            
            %----- if you want to fit msd curve----------------------------------
            
            intex=newid(['Enter the time cutoff (', app.timeunit, ') to be used for fitting'],...
                'Input for fit',...
                [1,40], {'5'});
            if isempty(intex)
                app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Fit cancelled';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            nintex=str2double(intex{1});
            if nintex <= max(t_avg_sd(:,1))
                nintex=str2double(intex);
            else
                nintex=max(t_avg_sd(:,1));
                
                ye=errordlg('Input exceeds max time, resetting to maximum time', 'Error: Input');
                pause(1)
                delete(ye)
                
            end
            numtimesteps=floor(nintex/app.interval);
            
            
            switch app.diffmodel
                case 'anomalous'
                    [deff,alpha, predy]= msd_fit_ARCmod_anomaly([0;t_avg_sd(1:numtimesteps,1)],...
                        [0;t_avg_sd(1:numtimesteps,2)]);
                    
                    hold(fjax,'on')
                    plot(fjax,...
                        [0;t_avg_sd(1:numtimesteps,1)], predy(1:numtimesteps+1),...
                        ':k', 'Linewidth',app.lwid+1),legend('off')
                    
                    
                    str1=['  D_{eff} = ',sprintf('%.3g', deff),' ',app.distunit, '^2/', app.timeunit];
                    str2= [',  \alpha = ',sprintf('%.3g', alpha)];
                    %str3=[',  c = ',sprintf('%.1g', ce)];
                    yla = ylim(gca);
                    xla = xlim(gca);
                    xlim(gca, [0 xla(2)]);
                    ylim(gca, [0 yla(2)]);
                    text(fjax,xla(1), yla(2), [str1,str2], ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 16, 'FontName', 'Times New Roman');
                    %             print(gcf, '-dtiffnocompression',...
                    %                 [app.subfolder, '/AvgMSD_vs_Time.tif']);
                    
                case 'drift'
                    [deff,v,predy]= msd_fit_ARCmod_drift([0;t_avg_sd(1:numtimesteps,1)],...
                        [0;t_avg_sd(1:numtimesteps,2)]);
                    
                    hold(fjax,'on')
                    plot(fjax,...
                        [0;t_avg_sd(1:numtimesteps,1)], predy(1:numtimesteps+1),...
                        ':k', 'Linewidth',app.lwid+1),legend('off')
                    
                    
                    str1=['  D_{eff} = ',sprintf('%.3g', deff),' ',app.distunit, '^2/', app.timeunit];
                    str2= [',  v = ',sprintf('%.3g', v)];
                    %str3=[',  c = ',sprintf('%.1g', ce1)];
                    yla = ylim(gca);
                    xla = xlim(gca);
                    xlim(gca, [0 xla(2)]);
                    ylim(gca, [0 yla(2)]);
                    text(fjax,xla(1), yla(2), [str1,str2], ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 16, 'FontName', 'Times New Roman');
                    %             print(gcf, '-dtiffnocompression',...
                    %                 [app.subfolder, '/AvgMSD_vs_Time.tif']);
                    
            end
            saveas(fj,[app.subfolder, '/msd-',app.diffmodel, '-', sprintf('%i',nintex),'.fig'], 'fig');
            clear xla yla str1 str2
            %             switch close(figure(10003))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
            
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
        end

        % Menu selected function: AveragewithfitMenu_2
        function AveragewithfitMenu_2Selected(app, event)
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='b';
            app.previewlabel.Text='MSD';
            drawnow
            switch ishandle(10003)
                case 1
                    close(10003)
            end
            fj=figure(10003);
            fjax=axes(fj);
            set(fj,'NumberTitle','off', 'Name', 'MSD Profiles')
            set(fjax,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Time (', app.timeunit, ')'])
            ylabel(['MSD (', app.distunit, '^2)'])
            
            for idd=1:app.objno
                loc=find(app.outmat(:,1)==app.un(idd));
                [dt{idd}, rr{idd}]=msdisplacement_ARCmod1(app.outmat(loc,5),...
                    app.outmat(loc,3)*app.pixsz,...
                    app.outmat(loc,4)*app.pixsz);
                id{idd}=ones(size(dt{idd},1),1)*idd;
                
                hold(fjax,'on')
                plot(fjax,dt{idd},rr{idd},'-',...
                    'Color', [0.8 0.8 0.8],...
                    'LineWidth', app.lwid)
                
            end
            
            
            %%
            %             print(gcf, '-dtiffnocompression',...
            %                 [app.subfolder, '/MSD_vs_Time.tif']);
            % text file stored
            catiddtrr=[cat(1, id{:}),cat(1, dt{:}),cat(1, rr{:})];
            fid =fopen([app.subfolder, '/ID_Time_MSD.txt'], 'w');
            fprintf(fid, ['ObjectID    Timestep (', app.timeunit, ')    MSD (', app.distunit, '^2/', app.timeunit, ')\r\n']);
            fclose(fid);
            dlmwrite([app.subfolder, '/ID_Time_MSD.txt'],...
                catiddtrr,'-append', 'delimiter',...
                '\t','newline', 'pc',...
                'precision', '%.3f');
            
            % average msd curve with fit
            t_avg_sd=[];
            
            for yen=app.interval:app.interval:max(catiddtrr(:,2))
                locating=find(catiddtrr(:,2)==yen);
                t_avg_sd= [t_avg_sd;...
                    yen,mean(catiddtrr(locating,3)),std(catiddtrr(locating,3))];
            end


            hold(fjax,'on')
            plot(fjax,...
                [0;t_avg_sd(:,1)],...
                [0;t_avg_sd(:,2)],...
                '-','Color', 'r', 'Linewidth', app.lwid+1)
            % shadedErrorBar([0;t_avg_sd(:,1)],...
            %                 [0;t_avg_sd(:,2)],[0;t_avg_sd(:,3)],...
            %                 'lineprops',{'-k', 'Linewidth',app.lwid+1},'transparent',1,...
            %                 'patchSaturation',0.033);
            xl = xlim(gca);
            xlim(gca, [0 xl(2)]);
            yl = ylim(gca);
            ylim(gca, [0 yl(2)]);
            
            %----- if you want to fit msd curve----------------------------------
            
            intex=newid(['Enter the time cutoff (', app.timeunit, ') to be used for fitting'],...
                'Input for fit',...
                [1,40], {'5'});
            if isempty(intex)
                app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Fit cancelled';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            nintex=str2double(intex{1});
            if nintex <= max(t_avg_sd(:,1))
                nintex=str2double(intex{1});
            else
                nintex=max(t_avg_sd(:,1));
                
                ye=errordlg('Input exceeds max time, resetting to maximum time', 'Error: Input');
                pause(1)
                delete(ye)
                
            end
            numtimesteps=floor(nintex/app.interval);
            switch app.diffmodel
                case 'anomalous'
                    [deff,alpha,predy]= msd_fit_ARCmod_anomaly([0;t_avg_sd(1:numtimesteps,1)],...
                        [0;t_avg_sd(1:numtimesteps,2)]);
                    
                    hold(fjax,'on')
                    plot(fjax,...
                        [0;t_avg_sd(1:numtimesteps,1)], predy(1:numtimesteps+1),...
                        ':k', 'Linewidth',app.lwid+1),legend('off')
                    
                    
                    str1=['  D_{eff} = ',sprintf('%.3g', deff),' ',app.distunit, '^2/', app.timeunit];
                    str2= [',  \alpha = ',sprintf('%.3g', alpha)];
                    %str3=[',  c = ',sprintf('%.1g', ce)];
                    yla = ylim(gca);
                    xla = xlim(gca);
                    xlim(gca, [0 xla(2)]);
                    ylim(gca, [0 yla(2)]);
                    text(fjax,xla(1), yla(2), [str1,str2], ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 16, 'FontName', 'Times New Roman');
                    %             print(gcf, '-dtiffnocompression',...
                    %                 [app.subfolder, '/AvgMSD_vs_Time.tif']);
                    saveas(fj,[app.subfolder, '/msd-', app.diffmodel, '-',sprintf('%i',nintex),'.fig'], 'fig');
                    
                case 'drift'
                    [deff,v,predy]= msd_fit_ARCmod_drift([0;t_avg_sd(1:numtimesteps,1)],...
                        [0;t_avg_sd(1:numtimesteps,2)]);
                    
                    hold(fjax,'on')
                    plot(fjax,...
                        [0;t_avg_sd(1:numtimesteps,1)], predy(1:numtimesteps+1),...
                        ':k', 'Linewidth',app.lwid+1),legend('off')
                    
                    
                    str1=['  D_{eff} = ',sprintf('%.3g', deff),' ',app.distunit, '^2/', app.timeunit];
                    str2= [',  v = ',sprintf('%.3g', v)];
                    %str3=[',  c = ',sprintf('%.1g', ce1)];
                    yla = ylim(gca);
                    xla = xlim(gca);
                    xlim(gca, [0 xla(2)]);
                    ylim(gca, [0 yla(2)]);
                    text(fjax,xla(1), yla(2), [str1,str2], ...
                        'HorizontalAlignment', 'left', ...
                        'VerticalAlignment', 'top', ...
                        'FontSize', 16, 'FontName', 'Times New Roman');
                    %             print(gcf, '-dtiffnocompression',...
                    %                 [app.subfolder, '/AvgMSD_vs_Time.tif']);
                    saveas(fj,[app.subfolder, '/msd-', app.diffmodel,'-', sprintf('%i',nintex),'.fig'], 'fig');
            end
            clear xla yla str1 str2
            %             switch close(10003)
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Image clicked function: savemovieimage
        function savemovieimageImageClicked(app, event)
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.5,0,0.5];
            app.previewlabel.Text='Saving movie..';
            drawnow
            
            o=moviemaker(app.subfolder,app.outmat,app.framestart,app.frameend,app.objno,app.img);
            if o==0
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Action cancelled';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
            else
                figure(app.DICOT10UIFigure);
                app.previewlabel.BackgroundColor=[0.5,0,0.5];
                app.previewlabel.Text='Saved movie.';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
            end
            
            
        end

        % Menu selected function: MakemovieMenu
        function MakemovieMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.5,0,0.5];
            app.previewlabel.Text='Saving movie..';
            drawnow
            
            o=moviemaker(app.subfolder,app.outmat,app.framestart,app.frameend,app.objno,app.img);
            if o==0
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Action cancelled';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
            else
                figure(app.DICOT10UIFigure);
                app.previewlabel.BackgroundColor=[0.5,0,0.5];
                app.previewlabel.Text='Saved movie.';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
            end
        end

        % Menu selected function: UserGuideMenu
        function UserGuideMenuSelected(app, event)
            open('DICOT_User_Guide.pdf');
        end

        % Menu selected function: PathlengthMenu
        function PathlengthMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.allpertrack)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Pathlength distribution');
            close(existingFig);  % Or "delete()"
            jf=figure(10009);
            kj=axes(jf);
            set(jf,'NumberTitle','off', 'Name', 'Pathlength distribution')
            histogram(kj,app.allpertrack(:,3));
            str1=['  \mu = ',sprintf('%.3g', mean(app.allpertrack(:,3))),' ',app.distunit];
            str2= [',  \sigma = ',sprintf('%.3g', std(app.allpertrack(:,3))),' ',app.distunit];
            yla = ylim(kj);
            xla = xlim(kj);
            xlim(gca, [0 xla(2)]);
            ylim(gca, [0 yla(2)]);
            text(kj,xla(1), yla(2), [str1,str2], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 16, 'FontName', 'Times New Roman');
            set(kj,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Pathlength (', app.distunit, ')'])
            ylabel('Frequency')
            %             switch close(figure(10009))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: SpeedMenu_2
        function SpeedMenu_2Selected(app, event)
            if isempty(app.subfolder) || isempty(app.allpertrack)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Speed distribution');
            close(existingFig);
            jf=figure(100010);
            kj=axes(jf);
            set(jf,'NumberTitle','off', 'Name', 'Speed distribution');
            histogram(kj,app.allpertrack(:,5));
            str1=['  \mu = ',sprintf('%.3g', mean(app.allpertrack(:,5))),' ',app.distunit, '/',app.timeunit];
            str2= [',  \sigma = ',sprintf('%.3g', std(app.allpertrack(:,5))),' ',app.distunit, '/',app.timeunit];
            yla = ylim(kj);
            xla = xlim(kj);
            xlim(gca, [0 xla(2)]);
            ylim(gca, [0 yla(2)]);
            text(kj,xla(1), yla(2), [str1,str2], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 16, 'FontName', 'Times New Roman');
            set(kj,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Speed (', app.distunit,  '/',app.timeunit,')'])
            ylabel('Frequency')
            %             switch close(figure(100010))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: InstantaneousMenu
        function InstantaneousMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.allinst)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Instantaneous displacement distribution');
            close(existingFig);
            jf1=figure(100012);
            kj1=axes(jf1);
            set(jf1,'NumberTitle','off', 'Name', 'Instantaneous displacement distribution');
            histogram(kj1,app.allinst(:,3));
            str1=['  \mu = ',sprintf('%.3g', mean(app.allinst(:,3))),' ',app.distunit];
            str2= [',  \sigma = ',sprintf('%.3g', std(app.allinst(:,3))),' ',app.distunit];
            yla = ylim(kj1);
            xla = xlim(kj1);
            xlim(gca, [0 xla(2)]);
            ylim(gca, [0 yla(2)]);
            text(kj1,xla(1), yla(2), [str1,str2], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 16, 'FontName', 'Times New Roman');
            set(kj1,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Instantaneous displacement (', app.distunit, ')'])
            ylabel('Frequency')
            %             switch close(figure(100012))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: SpeedMenu_3
        function SpeedMenu_3Selected(app, event)
            if isempty(app.subfolder) || isempty(app.allinst)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Speed profile');
            close(existingFig);
            jbb=figure(100010);
            set(jbb,'NumberTitle','off', 'Name', 'Speed profile');
            
            set(axes(jbb),'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Time (', app.timeunit, ')'])
            ylabel(['Speed (', app.distunit, '/', app.timeunit, ')'])
            
            for f=1:app.objno
                figure(jbb), hold on,
                plot([0;cumsum(app.allinst(app.allinst(:,1)==app.un(f),2))], [0;app.allinst(app.allinst(:,1)==app.un(f),4)],...
                    '-',...
                    'Color', [0.5,0.5,0.5])
            end
            %             switch close(figure(100010))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: NetMenu
        function NetMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.allpertrack)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Net displacement distribution');
            close(existingFig);
            jff=figure(100011);
            kjj=axes(jff);
            set(jff,'NumberTitle','off', 'Name', 'Net displacement distribution');
            histogram(kjj,app.allpertrack(:,4));
            str1=['  \mu = ',sprintf('%.3g', mean(app.allpertrack(:,4))),' ',app.distunit];
            str2= [',  \sigma = ',sprintf('%.3g', std(app.allpertrack(:,4))),' ',app.distunit];
            yla = ylim(kjj);
            xla = xlim(kjj);
            xlim(gca, [0 xla(2)]);
            ylim(gca, [0 yla(2)]);
            text(kjj,xla(1), yla(2), [str1,str2], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 16, 'FontName', 'Times New Roman');
            set(kjj,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Net displacement (', app.distunit, ')'])
            ylabel('Frequency')
            %             switch close(figure(100011))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: TortuosityMenu_2
        function TortuosityMenu_2Selected(app, event)
            if isempty(app.subfolder) || isempty(app.allpertrack)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Net displacement distribution');
            close(existingFig);
            jf2=figure(100011);
            kj2=axes(jf2);
            set(jf2,'NumberTitle','off', 'Name', 'Net displacement distribution');
            histogram(kj2,app.allpertrack(:,7));
            str1=['  \mu = ',sprintf('%.3g', mean(app.allpertrack(:,7)))];
            str2= [',  \sigma = ',sprintf('%.3g', std(app.allpertrack(:,7)))];
            yla = ylim(kj2);
            xla = xlim(kj2);
            xlim(gca, [0 xla(2)]);
            ylim(gca, [0 yla(2)]);
            text(kj2,xla(1), yla(2), [str1,str2], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 16, 'FontName', 'Times New Roman');
            set(kj2,'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel('Tortuosity')
            ylabel('Frequency')
            %             switch close(figure(100011))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: DisplacementMenu_3
        function DisplacementMenu_3Selected2(app, event)
            if isempty(app.subfolder) || isempty(app.allinst)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            existingFig = findobj(0,'Name', 'Displacement profile');
            close(existingFig);
            jb=figure(100009);
            set(jb,'NumberTitle','off', 'Name', 'Displacement profile');
            
            set(axes(jb),'fontname', 'Times New Roman', 'fontsize', 18)
            xlabel(['Time (', app.timeunit, ')'])
            ylabel(['Displacement (', app.distunit, ')'])
            
            for f=1:app.objno
                figure(jb), hold on,
                plot([0;cumsum(app.allinst(app.allinst(:,1)==app.un(f),2))], [0;cumsum(app.allinst(app.allinst(:,1)==app.un(f),3))],...
                    '-',...
                    'Color', [0.5,0.5,0.5])
            end
            %             switch close(figure(100009))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
        end

        % Menu selected function: AnomalousMenu
        function AnomalousMenuSelected(app, event)
            app.diffmodel='anomalous';
            drawnow
        end

        % Menu selected function: DriftMenu
        function DriftMenuSelected(app, event)
            app.diffmodel='drift';
            drawnow
        end

        % Image clicked function: saveparam
        function saveparamImageClicked(app, event)
            
            if isempty(app.img)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Open image first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
            end
            P={'Image Filename';...
                'Start frame';...
                'End frame';...
                'Distance unit';...
                'Time unit';...
                'Pixel size';...
                'Time interval';...
                'Type of objects detected';...
                'Kernel size';...
                'Sigma';...
                'Sensitivity factor';...
                'Search radius';...
                'Minimum track time';...
                'Diffusion model'};
            V=[NaN;
                app.framestart;
                app.frameend;
                NaN;
                NaN;
                app.pixsz;
                app.interval;
                NaN;
                app.ksize;
                app.sigma;
                app.phi;
                app.searchradius;
                app.perstime;
                NaN];
            %             switch app.lambda
            %                 case 1
            %                     typ='Bright';
            %                 case -1
            %                     typ='Dark';
            %             end
            U={app.fullfilename;
                '';
                '';
                app.distunit;
                app.timeunit;
                app.distunit;
                app.timeunit;
                '';
                'Pixels';
                'Pixels';
                app.typ;
                app.distunit;
                app.timeunit;
                app.diffmodel};
            T=table(P,V,U);
            T.Properties.VariableNames={ 'Parameter','Value', '-'};
            
            writetable(T,[app.subfolder, '/Parameters.txt'],...
                'WriteVariableNames',1,...
                'Delimiter', 'bar')
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0,0.5,0.1];
            app.previewlabel.Text='Saved parameter file.';
            drawnow
            %             sm=msgbox('Saved Parameter file.', '')
            %             pause(1);
            %             delete(sm);
            %             figure(app.DICOT10UIFigure);
            
        end

        % Menu selected function: TexttxtMenu
        function TexttxtMenuSelected(app, event)
            
            if isempty(app.img)
                app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Open image first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
            end
            P={'Image Filename';...
                'Start frame';...
                'End frame';...
                'Distance unit';...
                'Time unit';...
                'Pixel size';...
                'Time interval';...
                'Type of objects detected';...
                'Kernel size';...
                'Sigma';...
                'Sensitivity factor';...
                'Search radius';...
                'Minimum track time';...
                'Diffusion model'};
            V=[NaN;
                app.framestart;
                app.frameend;
                NaN;
                NaN;
                app.pixsz;
                app.interval;
                NaN;
                app.ksize;
                app.sigma;
                app.phi;
                app.searchradius;
                app.perstime;
                NaN];
            %             switch app.lambda
            %                 case 1
            %                     typ='Bright';
            %                 case -1
            %                     typ='Dark';
            %             end
            U={app.fullfilename;
                '';
                '';
                app.distunit;
                app.timeunit;
                app.distunit;
                app.timeunit;
                app.typ;
                'Pixels';
                'Pixels';
                '';
                app.distunit;
                app.timeunit;
                app.diffmodel};
            T=table(P,V,U);
            T.Properties.VariableNames={ 'Parameter','Value', '-'};
            writetable(T,[app.subfolder, '/Parameters.txt'],...
                'WriteVariableNames',1,...
                'Delimiter', 'bar')
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0,0.5,0.1];
            app.previewlabel.Text='Saved parameter file.';
            drawnow
            
        end

        % Menu selected function: ExcelsheetxlsxMenu
        function ExcelsheetxlsxMenuSelected(app, event)
            
            if isempty(app.img)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Open image first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
            end
            P={'Image Filename';...
                'Start frame';...
                'End frame';...
                'Distance unit';...
                'Time unit';...
                'Pixel size';...
                'Time interval';...
                'Type of objects detected';...
                'Kernel size';...
                'Sigma';...
                'Sensitivity factor';...
                'Search radius';...
                'Minimum track time';...
                'Diffusion model'};
            V=[NaN;
                app.framestart;
                app.frameend;
                NaN;
                NaN;
                app.pixsz;
                app.interval;
                NaN;
                app.ksize;
                app.sigma;
                app.phi;
                app.searchradius;
                app.perstime;
                NaN];
            %             switch app.lambda
            %                 case 1
            %                     typ='Bright';
            %                 case -1
            %                     typ='Dark';
            %             end
            U={app.fullfilename;
                '';
                '';
                app.distunit;
                app.timeunit;
                app.distunit;
                app.timeunit;
                app.typ;
                'Pixels';
                'Pixels';
                '';
                app.distunit;
                app.timeunit;
                app.diffmodel};
            T=table(P,V,U);
            T.Properties.VariableNames={ 'Parameter','Value', '-'};
            writetable(T,[app.subfolder, '/Parameters.xlsx'],...
                'WriteVariableNames',1)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0,0.5,0.1];
            app.previewlabel.Text='Saved parameter file.';
            drawnow
            
        end

        % Image clicked function: helpImage
        function helpImageClicked(app, event)
            open('DICOT_User_Guide.pdf');
        end

        % Image clicked function: resetimage
        function resetimageImageClicked(app, event)
            selection = questdlg('Do you want to reset?',...
                'Options',...
                'Yes','No', 'Yes');
            switch selection
                case 'Yes'
                    cla(app.UIAxes);
            app.Label.Text='';
            app.Label.Visible='off';
            app.framestart=1;
            app.frameend=1;
            app.distunit='um';
            app.timeunit='s';
            app.pixsz=1;
            app.interval=1;
            app.FramestartEditField.Value(1)=1;
            app.FrameendEditField.Value(1) = 1;
            app.PixelsizeEditField.Value(1)=1;
            app.pixelsizeunitlabel.Text='um/pixel';
            app.intervalunitlabel.Text='s';
            app.TimeintervalEditField.Value(1)=1;
            app.distUnitsDropDown.Value='um';
            app.timeUnitsDropDown.Value='s';
            
            app.typ='Bright';
            app.BrightButton.Value=1;
            app.DarkButton.Value=0;
            app.ksize=1;
            app.KernelsizeSpinner.Value=1;
            app.sigma=1;
            app.SigmaSpinner.Value=1;
            app.phi=1;
            app.SensitivityfactorSlider.Value=1;
            app.SensitivityfactorSpinner.Value=0;
            app.previewlabel.Visible='off';
            app.RawButton.Value=1;
            app.RawButton.Enable='off';
            app.DetectionButton.Enable='off';
            app.TrackingButton.Enable='off';
            
            app.searchradius=1;
            app.SearchradiusSpinner.Value=1;
            app.perstime=1;
            app.MinimaltracktimeSpinner.Value=5;
            app.fno=1;
            app.diffmodel='anomalous';
            app.frameSlider.Value=1;
            app.frameSlider.MajorTicks=[];
            app.frameSlider.Enable= 'off';
            app.searchradiusunits.Text='um';
            app.perstimeunitlabel.Text='s';
            app.threshpow=2;
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
                    
                case 'No'
                    
                    return
            end
            
        end

        % Image clicked function: statsimage
        function statsimageImageClicked(app, event)
            if isempty(app.subfolder) || isempty(app.allpertrack)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Save statistics first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
            return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            drawnow
            existingFig = findobj(0,'Name', 'Histograms');
            close(existingFig);
            jf1=figure(100012);
            kj1=axes(jf1);
            set(jf1,'NumberTitle','off', 'Name', 'Histograms');
            set(kj1,'fontname', 'Times New Roman', 'fontsize', 18)
            subplot(2,2,4)
            histogram(app.allinst(:,3));
            xlabel(['Instantaneous displacement (', app.distunit, ')'])
            ylabel('Frequency')
            
            subplot(2,2,2)
            histogram(app.allpertrack(:,5));
            xlabel(['Speed (', app.distunit,  '/',app.timeunit,')'])
            ylabel('Frequency')
            
            subplot(2,2,1)
            histogram(app.allpertrack(:,3));
            xlabel(['Pathlength (', app.distunit, ')'])
            ylabel('Frequency')
            
            subplot(2,2,3)
            histogram(app.allpertrack(:,7));
            xlabel('Tortuosity ')
            ylabel('Frequency')
            %             switch close(figure(100012))
            %                 case 1
            %                     figure(app.DICOT10UIFigure);
            %
            %             end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: SearchradiusSpinner
        function SearchradiusSpinnerValueChanged(app, event)
            
           app.TrackingButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.searchradius = app.SearchradiusSpinner.Value;
            app.previewlabel.Visible='off';
            drawnow
            
        end

        % Close request function: DICOT10UIFigure
        function DICOT10UIFigureCloseRequest(app, event)
            selection = questdlg('Do you want to quit?',...
                'Options',...
                'Yes','No', 'Yes');
            switch selection
                case 'Yes'
                    
                    delete(app);
                case 'No'
                    
                    return
            end
        end

        % Menu selected function: StatisticsMenu
        function StatisticsMenuSelected(app, event)
            app.previewlabel.Visible='off';
            drawnow
            if isempty(app.subfolder) || isempty(app.objno)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Track objects first';
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            
            
            app.allpertrack=[];
            app.allinst=[];
            drawnow
            return
            end
            [app.allpertrack,app.allinst]=objectstats1(app.subfolder,...
                app.objno,...
                app.pixsz,...
                app.distunit,...
                app.timeunit,...
                app.outmat,...
                app.un);
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.85,0.33,0.10];
            app.previewlabel.Text='Saved statistics files.';
            drawnow
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            strng=inputdlg('Save As..','s');
            if isempty(strng)
                % user pressed cancel
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                return
                %figure(app.DICOT10UIFigure);
            else
                switch ishandle(10000000)
                    case 1
                        close(10000000)
                end
                gg=figure(10000000);
                set(gg,'visible', 'off');
                ggax=axes(gg);
                %, 'InitialMagnification', 'fit')
                switch app.view
                    case 'Raw'
                        imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', ggax)
                        
                    case 'Detection'
                        imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', ggax)
                        switch app.filtertype
                            case 'SoG'
                                app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                                kk = fspecial('gaussian',app.ksize,app.sigma);
                                km = mean(kk(:));
                                ki = app.lambda*(kk - km) - app.phi.*km;
                            case 'DoG'
                                app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                                Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                                Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                                ki = app.lambda*(-Gauss_1 + Gauss_2);
                            case 'LoG'
                                app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                                ki = fspecial('log', app.ksize, app.sigma);
                                ki  = -app.lambda*ki;
                            case 'oDoG'
                                app.SigmaSpinner_2.Enable='on';
                                app.SensitivityfactorSlider.Enable='off';
                                app.SensitivityfactorSpinner.Enable='off';
                                app.SensitivityfactorSpinnerLabel.Enable='off';
                                drawnow
                                Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                                Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                                
                                %first expanded gaussian in the upper right corner of the matrix
                                G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                                G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                                %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                                
                                %second expanded gaussian in the lower left corner of the matrix
                                G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                                G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                                %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                                
                                %difference of offset gaussian
                                ki=zeros(2*app.ksize,2*app.ksize);
                                ki =  G1 - G2; %offset DoG
                        end
                        
                        
                        imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
                        imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                        % quantify
                        stat = regionprops(imgig,'Centroid');
                        scen = [stat.Centroid];
                        hold(ggax, 'on')
                        switch isempty(scen)
                            case 0
                                plot(ggax,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz-3),...
                            case 1
                            app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[1,0,0];
                            app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                            drawnow
                        end
                    case 'Tracking'
                        imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', ggax)
                        that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                        
                        for dd=1:numel(that)
                            hold(ggax,'on')
                            plot(ggax,...
                                app.ALLtracks{that(dd)}(:,3),...
                                app.ALLtracks{that(dd)}(:,4),...
                                'Color', app.rang(dd,:),...
                                'LineWidth', 0.5,...
                                'LineStyle','-',...
                                'Marker','none')
                            hold(ggax,'on')
                            plot(ggax,...
                                app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                                app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                                '.k',...
                                'MarkerSize',app.markersz-3)
                        end
                        
                end
                set(ggax, 'visible', 'off')
                
                f=getframe(gg);
                imwrite(f.cdata, [app.subfolder,'/', strng{1},'.tif'], 'tif', 'Compression', 'none');
                
                %copyUIAxes(app.UIAxes,gg);
                %set(gg,'visible', 'off');
                %print(gg,[app.subfolder,'/', strng{1}],'-dpng');
                delete(gg);
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: MinimaltracktimeSpinner
        function MinimaltracktimeSpinnerValueChanged(app, event)
            app.TrackingButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.perstime = app.MinimaltracktimeSpinner.Value;
            app.previewlabel.Visible='off';
            
            drawnow
            
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.sigma = app.SigmaSpinner.Value;
            app.sigma2=app.SigmaSpinner_2.Value;
            drawnow
            %-----preview------
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            %image_no=app.FramestartEditField.Value(1);
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    drawnow
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    drawnow
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    drawnow
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    drawnow
            end
            %--------end preview-----
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: KernelsizeSpinner
        function KernelsizeSpinnerValueChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.ksize = app.KernelsizeSpinner.Value;
            drawnow
            %-----preview------
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    drawnow
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    drawnow
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    drawnow
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    drawnow
            end
            %--------end preview-----
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: SensitivityfactorSpinner
        function SensitivityfactorSpinnerValueChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.phi = app.SensitivityfactorSpinner.Value;
            app.SensitivityfactorSlider.Value=app.phi;
            drawnow
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            %image_no=app.FramestartEditField.Value(1);
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    drawnow
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    drawnow
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    drawnow
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    drawnow
            end
            %--------end preview-----
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: SensitivityfactorSlider
        function SensitivityfactorSliderValueChanged(app, event)
            value = app.SensitivityfactorSlider.Value;
            
        end

        % Value changed function: SigmaSpinner_2
        function SigmaSpinner_2ValueChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.sigma = app.SigmaSpinner.Value;
            app.sigma2=app.SigmaSpinner_2.Value;
            
            drawnow
            %-----preview------
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
            
            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
            %image_no=app.FramestartEditField.Value(1);
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    drawnow
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    drawnow
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    drawnow
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    drawnow
            end
            %--------end preview-----
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            cla(app.UIAxes);
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.filtertype = app.FilterDropDown.Value;
            drawnow
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    %drawnow
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    %drawnow
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Menu selected function: MarkersizeMenu
        function MarkersizeMenuSelected(app, event)
            
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                
                drawnow
                
                return
            end
            app.markersz=str2double(inputdlg('Marker size (Please enter a number)','Input',[1,40],...
                {sprintf('%i', app.markersz)}));
            drawnow
            switch app.view
                case 'Raw'
                    cla(app.UIAxes);
                    if ~isempty(app.img)
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    end
                case 'Detection'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Detection mode';
                    drawnow
                    
                    imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', app.UIAxes)
                    switch app.filtertype
                        case 'SoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                            kk = fspecial('gaussian',app.ksize,app.sigma);
                            km = mean(kk(:));
                            ki = app.lambda*(kk - km) - app.phi.*km;
                        case 'DoG'
                            app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            ki = app.lambda*(-Gauss_1 + Gauss_2);
                        case 'LoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            ki = fspecial('log', app.ksize, app.sigma);
                            ki  = -app.lambda*ki;
                        case 'oDoG'
                            app.SigmaSpinner_2.Enable='on';
                            app.SensitivityfactorSlider.Enable='off';
                            app.SensitivityfactorSpinner.Enable='off';
                            app.SensitivityfactorSpinnerLabel.Enable='off';
                            drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            
                            %first expanded gaussian in the upper right corner of the matrix
                            G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                            G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                            
                            %second expanded gaussian in the lower left corner of the matrix
                            G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                            G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                            
                            %difference of offset gaussian
                            ki=zeros(2*app.ksize,2*app.ksize);
                            ki =  G1 - G2; %offset DoG
                    end
                    
                    
                    imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
                    imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                    % quantify
                    stat = regionprops(imgig,'Centroid');
                    scen = [stat.Centroid];
                    hold(app.UIAxes, 'on')
                    switch isempty(scen)
                        case 0
                            plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                                app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                        case 1
                            app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[1,0,0];
                            app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                            drawnow
                    end
                    
                    
                case 'Tracking'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Tracking mode';
                    drawnow
                    if ~isempty(app.img)
                        imshow(imadjust(app.img{app.fno}),...
                            'Border', 'tight',...
                            'Parent', app.UIAxes)
                    end
                    that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                    
                    for dd=1:numel(that)
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(:,3),...
                            app.ALLtracks{that(dd)}(:,4),...
                            'Color', app.rang(dd,:),...
                            'LineWidth', app.lwid,...
                            'LineStyle','-',...
                            'Marker','none')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                            '.k',...
                            'MarkerSize',app.markersz)
                    end
                    
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Menu selected function: LinewidthMenu
        function LinewidthMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                
                drawnow
                
                return
            end
            
            app.lwid=str2double(inputdlg('Line width (Please enter a number)','Input',[1,40],...
                {sprintf('%i', app.lwid)}));
            drawnow
            switch app.view
                case 'Raw'
                    cla(app.UIAxes);
                    if ~isempty(app.img)
                        imshow(imadjust(app.img{app.fno}),...
                            'Parent', app.UIAxes,...
                            'Border', 'tight',...
                            'InitialMagnification', 'fit');
                        app.previewlabel.Visible='on';
                        app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                        app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                    end
                case 'Detection'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Detection mode';
                    drawnow
                    
                    imshow(imadjust(app.img{app.fno}), 'Border', 'tight', 'Parent', app.UIAxes)
                    switch app.filtertype
                        case 'SoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    drawnow
                            kk = fspecial('gaussian',app.ksize,app.sigma);
                            km = mean(kk(:));
                            ki = app.lambda*(kk - km) - app.phi.*km;
                        case 'DoG'
                            app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            ki = app.lambda*(-Gauss_1 + Gauss_2);
                        case 'LoG'
                            app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                            ki = fspecial('log', app.ksize, app.sigma);
                            ki  = -app.lambda*ki;
                        case 'oDoG'
                            app.SigmaSpinner_2.Enable='on';
                            app.SensitivityfactorSlider.Enable='off';
                            app.SensitivityfactorSpinner.Enable='off';
                            app.SensitivityfactorSpinnerLabel.Enable='off';
                            drawnow
                            Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                            Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                            
                            %first expanded gaussian in the upper right corner of the matrix
                            G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                            G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                            
                            %second expanded gaussian in the lower left corner of the matrix
                            G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                            G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                            %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                            
                            %difference of offset gaussian
                            ki=zeros(2*app.ksize,2*app.ksize);
                            ki =  G1 - G2; %offset DoG
                    end
                    
                    
                    imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
                    imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                    % quantify
                    stat = regionprops(imgig,'Centroid');
                    scen = [stat.Centroid];
                    hold(app.UIAxes, 'on')
                    switch isempty(scen)
                        case 0
                            plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                                app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                            app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                        case 1
                            app.previewlabel.Visible='on';
                            app.previewlabel.BackgroundColor=[1,0,0];
                            app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                            drawnow
                    end
                    
                    
                case 'Tracking'
                    cla(app.UIAxes);
                    app.previewlabel.Visible='on';
                    app.previewlabel.Text='Tracking mode';
                    drawnow
                    imshow(imadjust(app.img{app.fno}),...
                        'Border', 'tight',...
                        'Parent', app.UIAxes)
                    that=unique(app.outmat(app.outmat(:,2)==app.fno,1));
                    
                    for dd=1:numel(that)
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(:,3),...
                            app.ALLtracks{that(dd)}(:,4),...
                            'Color', app.rang(dd,:),...
                            'LineWidth', app.lwid,...
                            'LineStyle','-',...
                            'Marker','none')
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,3),...
                            app.ALLtracks{that(dd)}(app.ALLtracks{that(dd)}(:,2)== app.fno,4),...
                            '.k',...
                            'MarkerSize',app.markersz)
                    end
                    
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Menu selected function: SetthresholdpowerMenu
        function SetthresholdpowerMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                drawnow
                
                return
            end
            inp=inputdlg('Please enter threshold power (p)\n Threshold= (Otsu threshold)^(1/p)','Input',[1,40],...
                {sprintf('%i', app.threshpow)});
            if isempty(inp)
                app.StatusLamp.Color= 'green';
                app.IdleLabel.Text='Idle';
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Action cancelled';
                return
            end
            
            app.threshpow=str2double(inp);
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            drawnow
            cla(app.UIAxes);
            imshow(imadjust(app.img{app.fno}),...
                'Border', 'tight',...
                'Parent', app.UIAxes)
            app.DetectionButton.Enable='on';
            app.DetectionButton.Value=1;
            app.view='Detection';
            app.filtertype = app.FilterDropDown.Value;
            drawnow
            
            switch app.filtertype
                case 'SoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='on';
                    app.SensitivityfactorSpinner.Enable='on';
                    app.SensitivityfactorSpinnerLabel.Enable='on';
                    kk = fspecial('gaussian',app.ksize,app.sigma);
                    km = mean(kk(:));
                    ki = app.lambda*(kk - km) - app.phi.*km;
                    drawnow
                case 'DoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    ki = app.lambda*(-Gauss_1 + Gauss_2);
                    drawnow
                case 'LoG'
                    app.SigmaSpinner_2.Enable='off';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    ki = fspecial('log', app.ksize, app.sigma);
                    ki  = -app.lambda*ki;
                    drawnow
                case 'oDoG'
                    app.SigmaSpinner_2.Enable='on';
                    app.SensitivityfactorSlider.Enable='off';
                    app.SensitivityfactorSpinner.Enable='off';
                    app.SensitivityfactorSpinnerLabel.Enable='off';
                    drawnow
                    Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                    Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                    
                    %first expanded gaussian in the upper right corner of the matrix
                    G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                    G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                    
                    %second expanded gaussian in the lower left corner of the matrix
                    G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                    G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                    %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                    
                    %difference of offset gaussian
                    ki=zeros(2*app.ksize,2*app.ksize);
                    ki =  G1 - G2; %offset DoG
                    
            end
            imgig = imadjust(imfilter(app.img{app.fno},ki,'replicate','same'));
            imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
            % quantify
            stat = regionprops(imgig,'Centroid');
            scen = [stat.Centroid];
            hold(app.UIAxes, 'on')
            switch isempty(scen)
                case 0
                    plot(app.UIAxes,scen(1:2:end),scen(2:2:end),'r.', 'MarkerSize', app.markersz),...
                        app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0.07,0.62,1.00];
                    app.previewlabel.Text='Preview mode ON. You may change detection parameters to improve segmentation';
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[1,0,0];
                    app.previewlabel.Text='Please adjust detection parameters. No objects found.';
                    
            end
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
        end

        % Value changed function: frameSlider
        function frameSliderValueChanged(app, event)
            
            app.frameSlider.Tooltip=num2str(round(app.frameSlider.Value));
        end

        % Menu selected function: csvcsvMenu
        function csvcsvMenuSelected(app, event)
            if isempty(app.img)
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor='r';
            app.previewlabel.Text='Error: Open image first';
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            return
            end
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
            end
            P={'Image Filename';...
                'Start frame';...
                'End frame';...
                'Distance unit';...
                'Time unit';...
                'Pixel size';...
                'Time interval';...
                'Type of objects detected';...
                'Kernel size';...
                'Sigma';...
                'Sensitivity factor';...
                'Search radius';...
                'Minimum track time';...
                'Diffusion model'};
            V=[NaN;
                app.framestart;
                app.frameend;
                NaN;
                NaN;
                app.pixsz;
                app.interval;
                NaN;
                app.ksize;
                app.sigma;
                app.phi;
                app.searchradius;
                app.perstime;
                NaN];
            %             switch app.lambda
            %                 case 1
            %                     typ='Bright';
            %                 case -1
            %                     typ='Dark';
            %             end
            U={app.fullfilename;
                '';
                '';
                app.distunit;
                app.timeunit;
                app.distunit;
                app.timeunit;
                '';
                'Pixels';
                'Pixels';
                app.typ;
                app.distunit;
                app.timeunit;
                app.diffmodel};
            T=table(P,V,U);
            T.Properties.VariableNames={ 'Parameter','Value', '-'};
            
            writetable(T,[app.subfolder, '/Parameters.csv'],...
                'WriteVariableNames',1,...
                'Delimiter', ',')
            app.previewlabel.Visible='on';
            app.previewlabel.BackgroundColor=[0,0.5,0.1];
            app.previewlabel.Text='Saved parameter file.';
            drawnow
            %             sm=msgbox('Saved Parameter file.', '')
            %             pause(1);
            %             delete(sm);
            %             figure(app.DICOT10UIFigure);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
           selection = questdlg('Do you want to quit?',...
                'Options',...
                'Yes','No', 'Yes');
            switch selection
                case 'Yes'
                    
                    delete(app);
                case 'No'
                    
                    return
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'GNU GENERAL PUBLIC LICENSE';''; 'DICOT is a MATLAB-based software developed for 2D particle tracking in DIC images.';...
                'Authors: Anushree Chaphalkar, Yash Jawale, Dhruv Khatri, Chaitanya Athale';...
                'IISER Pune'; '';'Version 1.0'}, 'DICOT 1.0');
            
        end

        % Menu selected function: oDoGMenu
        function oDoGMenuSelected(app, event)
            if isempty(app.subfolder) || isempty(app.img)
                app.previewlabel.Visible='on';
                app.previewlabel.BackgroundColor='r';
                app.previewlabel.Text='Error: Open image first';
                drawnow
                
                return
            end
            app.SensitivityfactorSlider.Enable='off';
            app.SensitivityfactorSpinner.Enable='off';
            app.SensitivityfactorSpinnerLabel.Enable='off';
            app.SigmaSpinner_2.Enable='on';
            app.FilterDropDown.Value='DoG';
            app.DetectionButton.Enable='on';
            app.view='Detection';
            app.StatusLamp.Color= 'red';
            app.IdleLabel.Text='Busy';
            app.previewlabel.Visible='off';
            drawnow
            %---code---
            listLen= cell(1,max(app.Findex));
            switch exist(app.subfolder, 'dir')
                case 0
                    mkdir(app.subfolder);
                    %                 case 1
                    %                     app.previewlabel.Visible= 'on';
                    %                     app.previewlabel.BackgroundColor='r';
                    %                     app.previewlabel.Text='Please rename the previously existing folder to prevent overwriting.';
                    %                     drawnow
                    
            end
            %
            p=0;
            
            w =waitbar(0,'Detecting objects..',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(w,'canceling',0);
            
            for image_no=app.Findex
                p=p+1;
                waitbar(p/numel(app.Findex));
                % Check for clicked Cancel button
                if getappdata(w,'canceling')
                    delete(w);
                    app.StatusLamp.Color= 'green';
                    app.IdleLabel.Text='Idle';
                    app.segmented=[];
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: Action cancelled';
                    drawnow
                    return
                end
                % dog
                Gauss_1 = fspecial('gaussian', app.ksize, max(app.sigma,app.sigma2));
                Gauss_2 = fspecial('gaussian', app.ksize, min(app.sigma,app.sigma2));
                
                %first expanded gaussian in the upper right corner of the matrix
                G1 = zeros(2*app.ksize,2*app.ksize);%make a blank filter
                G1((app.ksize+1):2*app.ksize,(app.ksize+1):2*app.ksize)=Gauss_1;%put the 2nd gaussian in a corner
                %figure(1),subplot(2,2,1),pcolor(G1),title('G1')
                
                %second expanded gaussian in the lower left corner of the matrix
                G2=zeros(2*app.ksize,2*app.ksize);% make a blank filter
                G2(1:app.ksize,1:app.ksize)=Gauss_2;%put the 2nd gaussian in a corner
                %figure(1),subplot(2,2,2),pcolor(G2),title('G2')
                
                %difference of offset gaussian
                ki=zeros(2*app.ksize,2*app.ksize);
                ki =  G1 - G2; %offset DoG
                
                
                imgig = imadjust(imfilter(app.img{image_no},ki,'replicate','same'));
                imgig = imbinarize(imgig,graythresh(imgig)^(1/app.threshpow));
                % quantify
                stat = regionprops(imgig,'Centroid');
                scen = cat(1,stat.Centroid);
                frameno=(ones(size(scen,1),1))*image_no;
                listLen{image_no}=[frameno,scen];
            end
            
            delete(w)
            figure(app.DICOT10UIFigure);
            
            try
                app.segmented = cat(1, listLen{:});%1:frame no, 2:obj no, 3:x, 4:y
            catch ME
                if (strcmp(ME.identifier,'MATLAB:catenate:dimensionMismatch'))
                    %msg = 'Please tweak parameters';
                    causeException = MException('MATLAB:myCode:dimensions',msg);
                    ME = addCause(ME,causeException);
                end
            end
            switch isempty(app.segmented)
                case 1
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor='r';
                    app.previewlabel.Text='Error: No objects detected. Please tweak parameters.';
                    %drawnow
                case 0
                    fid =fopen([app.subfolder,'/segmented.txt'], 'w');
                    fprintf(fid, 'Frame    X    Y\r\n');
                    fclose(fid);
                    dlmwrite([app.subfolder,'/segmented.txt'], app.segmented, '-append',...
                        'delimiter', '\t','newline', 'pc','precision', '%.3f');
                    app.previewlabel.Visible='on';
                    app.previewlabel.BackgroundColor=[0,0.2,0.6];
                    app.previewlabel.Text='Detection complete.';
                    %drawnow
            end
            
            app.StatusLamp.Color= 'green';
            app.IdleLabel.Text='Idle';
            drawnow
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DICOT10UIFigure = uifigure('Visible', 'off');
            app.DICOT10UIFigure.Name = 'DICOT 1.0';
            app.DICOT10UIFigure.CloseRequestFcn = createCallbackFcn(app, @DICOT10UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DICOT10UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenInputImageseriesMenu = uimenu(app.FileMenu);
            app.OpenInputImageseriesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenInputImageseriesMenuSelected, true);
            app.OpenInputImageseriesMenu.Tooltip = {'Open image-series (.tif)'};
            app.OpenInputImageseriesMenu.Text = 'Open Input Image-series';
            app.SaveImageMenu = uimenu(app.FileMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Text = 'Save Image ';
            app.SaveParametersAsMenu = uimenu(app.FileMenu);
            app.SaveParametersAsMenu.Text = 'Save Parameters As..';
            app.ExcelsheetxlsxMenu = uimenu(app.SaveParametersAsMenu);
            app.ExcelsheetxlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelsheetxlsxMenuSelected, true);
            app.ExcelsheetxlsxMenu.Text = 'Excel sheet (.xlsx)';
            app.TexttxtMenu = uimenu(app.SaveParametersAsMenu);
            app.TexttxtMenu.MenuSelectedFcn = createCallbackFcn(app, @TexttxtMenuSelected, true);
            app.TexttxtMenu.Text = 'Text (.txt)';
            app.csvcsvMenu = uimenu(app.SaveParametersAsMenu);
            app.csvcsvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvcsvMenuSelected, true);
            app.csvcsvMenu.Text = 'csv (.csv)';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.OperationsMenu = uimenu(app.DICOT10UIFigure);
            app.OperationsMenu.Text = 'Operations';
            app.DetectobjectsMenu = uimenu(app.OperationsMenu);
            app.DetectobjectsMenu.Text = 'Detect objects';
            app.SoGMenu = uimenu(app.DetectobjectsMenu);
            app.SoGMenu.MenuSelectedFcn = createCallbackFcn(app, @SoGMenuSelected, true);
            app.SoGMenu.Text = 'SoG';
            app.LoGMenu = uimenu(app.DetectobjectsMenu);
            app.LoGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoGMenuSelected, true);
            app.LoGMenu.Text = 'LoG';
            app.DoGMenu = uimenu(app.DetectobjectsMenu);
            app.DoGMenu.MenuSelectedFcn = createCallbackFcn(app, @DoGMenuSelected, true);
            app.DoGMenu.Text = 'DoG';
            app.oDoGMenu = uimenu(app.DetectobjectsMenu);
            app.oDoGMenu.MenuSelectedFcn = createCallbackFcn(app, @oDoGMenuSelected, true);
            app.oDoGMenu.Text = 'oDoG';
            app.TrackobjectsMenu = uimenu(app.OperationsMenu);
            app.TrackobjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackobjectsMenuSelected, true);
            app.TrackobjectsMenu.Text = 'Track objects';
            app.StatisticsMenu = uimenu(app.OperationsMenu);
            app.StatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @StatisticsMenuSelected, true);
            app.StatisticsMenu.Text = 'Statistics';
            app.MakemovieMenu = uimenu(app.OperationsMenu);
            app.MakemovieMenu.MenuSelectedFcn = createCallbackFcn(app, @MakemovieMenuSelected, true);
            app.MakemovieMenu.Text = 'Make movie';
            app.AdvancedMenu = uimenu(app.DICOT10UIFigure);
            app.AdvancedMenu.Text = 'Advanced';
            app.SetdiffusionmodelMenu = uimenu(app.AdvancedMenu);
            app.SetdiffusionmodelMenu.Text = 'Set diffusion model';
            app.AnomalousMenu = uimenu(app.SetdiffusionmodelMenu);
            app.AnomalousMenu.MenuSelectedFcn = createCallbackFcn(app, @AnomalousMenuSelected, true);
            app.AnomalousMenu.Text = 'Anomalous';
            app.DriftMenu = uimenu(app.SetdiffusionmodelMenu);
            app.DriftMenu.MenuSelectedFcn = createCallbackFcn(app, @DriftMenuSelected, true);
            app.DriftMenu.Text = 'Drift';
            app.SetthresholdpowerMenu = uimenu(app.AdvancedMenu);
            app.SetthresholdpowerMenu.MenuSelectedFcn = createCallbackFcn(app, @SetthresholdpowerMenuSelected, true);
            app.SetthresholdpowerMenu.Text = 'Set threshold power';
            app.PlotMenu = uimenu(app.DICOT10UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.HistogramMenu = uimenu(app.PlotMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.PathlengthMenu = uimenu(app.HistogramMenu);
            app.PathlengthMenu.MenuSelectedFcn = createCallbackFcn(app, @PathlengthMenuSelected, true);
            app.PathlengthMenu.Text = 'Pathlength';
            app.DisplacementMenu_2 = uimenu(app.HistogramMenu);
            app.DisplacementMenu_2.Text = 'Displacement';
            app.InstantaneousMenu = uimenu(app.DisplacementMenu_2);
            app.InstantaneousMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousMenuSelected, true);
            app.InstantaneousMenu.Text = 'Instantaneous';
            app.NetMenu = uimenu(app.DisplacementMenu_2);
            app.NetMenu.MenuSelectedFcn = createCallbackFcn(app, @NetMenuSelected, true);
            app.NetMenu.Text = 'Net';
            app.SpeedMenu_2 = uimenu(app.HistogramMenu);
            app.SpeedMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpeedMenu_2Selected, true);
            app.SpeedMenu_2.Text = 'Speed';
            app.TortuosityMenu_2 = uimenu(app.HistogramMenu);
            app.TortuosityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TortuosityMenu_2Selected, true);
            app.TortuosityMenu_2.Text = 'Tortuosity';
            app.TimeprofilesMenu = uimenu(app.PlotMenu);
            app.TimeprofilesMenu.Text = 'Time profiles';
            app.DisplacementMenu_3 = uimenu(app.TimeprofilesMenu);
            app.DisplacementMenu_3.MenuSelectedFcn = createCallbackFcn(app, @DisplacementMenu_3Selected2, true);
            app.DisplacementMenu_3.Text = 'Displacement';
            app.SpeedMenu_3 = uimenu(app.TimeprofilesMenu);
            app.SpeedMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SpeedMenu_3Selected, true);
            app.SpeedMenu_3.Text = 'Speed';
            app.MSDMenu_4 = uimenu(app.TimeprofilesMenu);
            app.MSDMenu_4.Text = 'MSD';
            app.AveragewithfitMenu_2 = uimenu(app.MSDMenu_4);
            app.AveragewithfitMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AveragewithfitMenu_2Selected, true);
            app.AveragewithfitMenu_2.Text = 'Average with fit';
            app.OptionsMenu = uimenu(app.PlotMenu);
            app.OptionsMenu.Text = 'Options..';
            app.MarkersizeMenu = uimenu(app.OptionsMenu);
            app.MarkersizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MarkersizeMenuSelected, true);
            app.MarkersizeMenu.Text = 'Marker size';
            app.LinewidthMenu = uimenu(app.OptionsMenu);
            app.LinewidthMenu.MenuSelectedFcn = createCallbackFcn(app, @LinewidthMenuSelected, true);
            app.LinewidthMenu.Text = 'Line width';
            app.HelpMenu = uimenu(app.DICOT10UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UserGuideMenu = uimenu(app.HelpMenu);
            app.UserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @UserGuideMenuSelected, true);
            app.UserGuideMenu.Text = 'User Guide';
            app.UIAxes = uiaxes(app.DICOT10UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.DICOT10UIFigure);
            app.Label.Text = '';
            app.Panel = uipanel(app.DICOT10UIFigure);
            app.previewlabel = uilabel(app.Panel);
            app.previewlabel.Visible = 'off';
            app.previewlabel.Text = '';
            app.StatusLamp = uilamp(app.Panel);
            app.StatusLamp.Tooltip = {'Status indicator'};
            app.IdleLabel = uilabel(app.Panel);
            app.IdleLabel.Text = 'Idle';
            app.saveparam = uiimage(app.Panel);
            app.saveparam.ImageClickedFcn = createCallbackFcn(app, @saveparamImageClicked, true);
            app.saveparam.ImageSource = 'save.png';
            app.resetimage = uiimage(app.Panel);
            app.resetimage.ImageClickedFcn = createCallbackFcn(app, @resetimageImageClicked, true);
            app.resetimage.ImageSource = 'reset.png';
            app.savemovieimage = uiimage(app.Panel);
            app.savemovieimage.ImageClickedFcn = createCallbackFcn(app, @savemovieimageImageClicked, true);
            app.savemovieimage.Tooltip = {'Save tracking movie'};
            app.savemovieimage.ImageSource = 'savemovieicon.png';
            app.statsimage = uiimage(app.Panel);
            app.statsimage.ImageClickedFcn = createCallbackFcn(app, @statsimageImageClicked, true);
            app.statsimage.ImageSource = 'Statistics.png';
            app.helpImage = uiimage(app.Panel);
            app.helpImage.ImageClickedFcn = createCallbackFcn(app, @helpImageClicked, true);
            app.helpImage.ImageSource = 'help.png';
            app.openimage = uiimage(app.Panel);
            app.openimage.ImageClickedFcn = createCallbackFcn(app, @openimageImageClicked, true);
            app.openimage.ImageSource = 'open.png';
            app.Panel_2 = uipanel(app.DICOT10UIFigure);
            app.PixelsizeEditFieldLabel = uilabel(app.Panel_2);
            app.PixelsizeEditFieldLabel.Tooltip = {'Pixel-to-Unit conversion factor'};
            app.PixelsizeEditFieldLabel.Text = 'Pixel size';
            app.PixelsizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.PixelsizeEditField.Limits = [0 Inf];
            app.PixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeEditFieldValueChanged, true);
            app.PixelsizeEditField.Tooltip = {'Pixel-to-Unit conversion factor'};
            app.PixelsizeEditField.Value = 1;
            app.TimeintervalEditFieldLabel = uilabel(app.Panel_2);
            app.TimeintervalEditFieldLabel.Tooltip = {'Time gap between two consecutive frames'};
            app.TimeintervalEditFieldLabel.Text = 'Time interval';
            app.TimeintervalEditField = uieditfield(app.Panel_2, 'numeric');
            app.TimeintervalEditField.Limits = [0 Inf];
            app.TimeintervalEditField.ValueChangedFcn = createCallbackFcn(app, @TimeintervalEditFieldValueChanged, true);
            app.TimeintervalEditField.Tooltip = {'Time gap between two consecutive frames'};
            app.TimeintervalEditField.Value = 1;
            app.timeUnitsDropDown = uidropdown(app.Panel_2);
            app.timeUnitsDropDown.Items = {'s', 'min', 'frame', 'h'};
            app.timeUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @timeUnitsDropDownValueChanged, true);
            app.timeUnitsDropDown.Tooltip = {'Time units'};
            app.timeUnitsDropDown.Value = 's';
            app.toEditFieldLabel = uilabel(app.Panel_2);
            app.toEditFieldLabel.Text = 'to';
            app.FrameendEditField = uieditfield(app.Panel_2, 'numeric');
            app.FrameendEditField.Limits = [1 Inf];
            app.FrameendEditField.RoundFractionalValues = 'on';
            app.FrameendEditField.ValueDisplayFormat = '%.0f';
            app.FrameendEditField.ValueChangedFcn = createCallbackFcn(app, @FrameendEditFieldValueChanged, true);
            app.FrameendEditField.Tooltip = {'Upto image number..'};
            app.FrameendEditField.Value = 1;
            app.pixelsizeunitlabel = uilabel(app.Panel_2);
            app.pixelsizeunitlabel.Text = 'um/pixel';
            app.intervalunitlabel = uilabel(app.Panel_2);
            app.intervalunitlabel.Text = 's';
            app.UnitsDropDownLabel = uilabel(app.Panel_2);
            app.UnitsDropDownLabel.Text = 'Units';
            app.distUnitsDropDown = uidropdown(app.Panel_2);
            app.distUnitsDropDown.Items = {'um', 'nm', 'mm', 'pix'};
            app.distUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @distUnitsDropDownValueChanged, true);
            app.distUnitsDropDown.Tooltip = {'Length units'};
            app.distUnitsDropDown.Value = 'um';
            app.FramesEditFieldLabel = uilabel(app.Panel_2);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramestartEditField = uieditfield(app.Panel_2, 'numeric');
            app.FramestartEditField.Limits = [1 Inf];
            app.FramestartEditField.RoundFractionalValues = 'on';
            app.FramestartEditField.ValueDisplayFormat = '%.0f';
            app.FramestartEditField.ValueChangedFcn = createCallbackFcn(app, @FramestartEditFieldValueChanged, true);
            app.FramestartEditField.Tooltip = {'Start from image number..'};
            app.FramestartEditField.Value = 1;
            app.ObjectdetectionPanel = uipanel(app.DICOT10UIFigure);
            app.ObjectdetectionPanel.Title = 'Object detection';
            app.SensitivityfactorSlider = uislider(app.ObjectdetectionPanel);
            app.SensitivityfactorSlider.Limits = [-2 2];
            app.SensitivityfactorSlider.MajorTicks = [-2 -1 0 1 2];
            app.SensitivityfactorSlider.MajorTickLabels = {'-2', '-1', '0', '1', '2'};
            app.SensitivityfactorSlider.ValueChangedFcn = createCallbackFcn(app, @SensitivityfactorSliderValueChanged, true);
            app.SensitivityfactorSlider.ValueChangingFcn = createCallbackFcn(app, @SensitivityfactorSliderValueChanging, true);
            app.SensitivityfactorSlider.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SensitivityfactorSlider.Tooltip = {'1'};
            app.TypeofobjectstobedetectedButtonGroup = uibuttongroup(app.ObjectdetectionPanel);
            app.TypeofobjectstobedetectedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofobjectstobedetectedButtonGroupSelectionChanged, true);
            app.TypeofobjectstobedetectedButtonGroup.Title = 'Type of objects to be detected';
            app.BrightButton = uiradiobutton(app.TypeofobjectstobedetectedButtonGroup);
            app.BrightButton.Text = 'Bright';
            app.BrightButton.Value = true;
            app.DarkButton = uiradiobutton(app.TypeofobjectstobedetectedButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.toEditFieldLabel_2 = uilabel(app.ObjectdetectionPanel);
            app.toEditFieldLabel_2.Text = 'pixels';
            app.toEditFieldLabel_3 = uilabel(app.ObjectdetectionPanel);
            app.toEditFieldLabel_3.Text = 'pixels';
            app.DetectobjectsButton = uibutton(app.ObjectdetectionPanel, 'push');
            app.DetectobjectsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectobjectsButtonPushed, true);
            app.DetectobjectsButton.Text = 'Detect objects';
            app.SensitivityfactorSpinnerLabel = uilabel(app.ObjectdetectionPanel);
            app.SensitivityfactorSpinnerLabel.Tooltip = {''};
            app.SensitivityfactorSpinnerLabel.Text = 'Sensitivity factor';
            app.SensitivityfactorSpinner = uispinner(app.ObjectdetectionPanel);
            app.SensitivityfactorSpinner.Step = 0.001;
            app.SensitivityfactorSpinner.Limits = [-2 2];
            app.SensitivityfactorSpinner.ValueDisplayFormat = '%1.4g';
            app.SensitivityfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @SensitivityfactorSpinnerValueChanged, true);
            app.SensitivityfactorSpinner.Tooltip = {'For scaling intensities'};
            app.SigmaSpinnerLabel = uilabel(app.ObjectdetectionPanel);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.ObjectdetectionPanel);
            app.SigmaSpinner.Step = 0.01;
            app.SigmaSpinner.ValueDisplayFormat = '%2.3g';
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged, true);
            app.SigmaSpinner.Tooltip = {'SD of Gaussian filter'};
            app.SigmaSpinner.Value = 1;
            app.KernelsizeSpinnerLabel = uilabel(app.ObjectdetectionPanel);
            app.KernelsizeSpinnerLabel.Text = 'Kernel size';
            app.KernelsizeSpinner = uispinner(app.ObjectdetectionPanel);
            app.KernelsizeSpinner.Step = 2;
            app.KernelsizeSpinner.Limits = [1 Inf];
            app.KernelsizeSpinner.ValueDisplayFormat = '%.0f';
            app.KernelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @KernelsizeSpinnerValueChanged, true);
            app.KernelsizeSpinner.Tooltip = {'Size of Gaussian filter. Choose an ODD number.'};
            app.KernelsizeSpinner.Value = 1;
            app.FilterDropDownLabel = uilabel(app.ObjectdetectionPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.ObjectdetectionPanel);
            app.FilterDropDown.Items = {'SoG', 'DoG', 'LoG', 'oDoG'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'SoG';
            app.SigmaSpinner_2 = uispinner(app.ObjectdetectionPanel);
            app.SigmaSpinner_2.Step = 0.01;
            app.SigmaSpinner_2.ValueDisplayFormat = '%2.3g';
            app.SigmaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinner_2ValueChanged, true);
            app.SigmaSpinner_2.Enable = 'off';
            app.SigmaSpinner_2.Tooltip = {'SD of Gaussian filter (2)'};
            app.SigmaSpinner_2.Value = 1;
            app.TrackingPanel = uipanel(app.DICOT10UIFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.TrackobjectsButton = uibutton(app.TrackingPanel, 'push');
            app.TrackobjectsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackobjectsButtonPushed, true);
            app.TrackobjectsButton.Text = 'Track objects';
            app.searchradiusunits = uilabel(app.TrackingPanel);
            app.searchradiusunits.Text = 'um';
            app.perstimeunitlabel = uilabel(app.TrackingPanel);
            app.perstimeunitlabel.Text = 's';
            app.SearchradiusSpinnerLabel = uilabel(app.TrackingPanel);
            app.SearchradiusSpinnerLabel.Text = 'Search radius';
            app.SearchradiusSpinner = uispinner(app.TrackingPanel);
            app.SearchradiusSpinner.Limits = [0 Inf];
            app.SearchradiusSpinner.ValueDisplayFormat = '%5.3g';
            app.SearchradiusSpinner.ValueChangedFcn = createCallbackFcn(app, @SearchradiusSpinnerValueChanged, true);
            app.SearchradiusSpinner.Tooltip = {'Max distance travelled by object in between two frames'};
            app.SearchradiusSpinner.Value = 1;
            app.MinimaltracktimeSpinnerLabel = uilabel(app.TrackingPanel);
            app.MinimaltracktimeSpinnerLabel.Text = 'Minimal track time';
            app.MinimaltracktimeSpinner = uispinner(app.TrackingPanel);
            app.MinimaltracktimeSpinner.Limits = [0 Inf];
            app.MinimaltracktimeSpinner.ValueDisplayFormat = '%5.3g';
            app.MinimaltracktimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimaltracktimeSpinnerValueChanged, true);
            app.MinimaltracktimeSpinner.Tooltip = {'Min number of time-points in a track'};
            app.MinimaltracktimeSpinner.Value = 5;
            app.MSDButton = uibutton(app.DICOT10UIFigure, 'push');
            app.MSDButton.ButtonPushedFcn = createCallbackFcn(app, @MSDButtonPushed, true);
            app.MSDButton.Tooltip = {''};
            app.MSDButton.Text = 'MSD';
            app.SavestatisticsButton = uibutton(app.DICOT10UIFigure, 'push');
            app.SavestatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @SavestatisticsButtonPushed, true);
            app.SavestatisticsButton.Tooltip = {''};
            app.SavestatisticsButton.Text = 'Save statistics';
            app.frameSlider = uislider(app.DICOT10UIFigure);
            app.frameSlider.MajorTicks = [];
            app.frameSlider.ValueChangedFcn = createCallbackFcn(app, @frameSliderValueChanged, true);
            app.frameSlider.ValueChangingFcn = createCallbackFcn(app, @frameSliderValueChanging, true);
            app.frameSlider.MinorTicks = [];
            app.frameSlider.Enable = 'off';
            app.frameSlider.Tooltip = {''};
            app.viewmodegroup = uibuttongroup(app.DICOT10UIFigure);
            app.viewmodegroup.SelectionChangedFcn = createCallbackFcn(app, @viewmodegroupSelectionChanged, true);
            app.DetectionButton = uiradiobutton(app.viewmodegroup);
            app.DetectionButton.Enable = 'off';
            app.DetectionButton.Text = 'Detection';
            app.TrackingButton = uiradiobutton(app.viewmodegroup);
            app.TrackingButton.Enable = 'off';
            app.TrackingButton.Text = 'Tracking';
            app.RawButton = uiradiobutton(app.viewmodegroup);
            app.RawButton.Enable = 'off';
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.toEditFieldLabel_6 = uilabel(app.DICOT10UIFigure);
            app.toEditFieldLabel_6.Text = 'View Mode';
            app.toEditFieldLabel_7 = uilabel(app.DICOT10UIFigure);
            app.toEditFieldLabel_7.Text = 'Frame slider';
            % Show the figure after all components are created
            app.DICOT10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dicot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DICOT10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DICOT10UIFigure)
        end
    end
end